classdef azfpdiag_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        HelpButton                     matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        FileSummaryTab                 matlab.ui.container.Tab
        StartDatefromdplfileTextArea   matlab.ui.control.TextArea
        StartDatefromdplfileTextAreaLabel  matlab.ui.control.Label
        InstallMiKTeXButton            matlab.ui.control.Button
        DeploymentFileTextArea         matlab.ui.control.TextArea
        DeploymentFileTextAreaLabel    matlab.ui.control.Label
        LogFileOutputTextArea          matlab.ui.control.TextArea
        LogFileOutputTextAreaLabel     matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        SN                             matlab.ui.control.Label
        text17                         matlab.ui.control.Label
        FileSummaryLabel               matlab.ui.control.Label
        FileSummaryTable               matlab.ui.control.Table
        RawFileTextLoc                 matlab.ui.control.Label
        Load                           matlab.ui.control.Button
        FileSizeAxes                   matlab.ui.control.UIAxes
        SensorSummaryTab               matlab.ui.container.Tab
        RawFileTextLoc3                matlab.ui.control.Label
        MonthlySensorDataSummaryLabel  matlab.ui.control.Label
        SummaryTable                   matlab.ui.control.Table
        axes1                          matlab.ui.control.UIAxes
        EchogramsTab                   matlab.ui.container.Tab
        ULS6Phase                      matlab.ui.control.DropDown
        ULS6DropDownLabel              matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        Load_2                         matlab.ui.control.Button
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_1                         matlab.ui.control.Lamp
        InstrumentSettingsPanel        matlab.ui.container.Panel
        UsePsensor                     matlab.ui.control.CheckBox
        WaterTempSpinner               matlab.ui.control.Spinner
        WaterTempdegCSpinnerLabel      matlab.ui.control.Label
        Channel4Spinner                matlab.ui.control.Spinner
        Channel4SpinnerLabel           matlab.ui.control.Label
        Channel3Spinner                matlab.ui.control.Spinner
        Channel3SpinnerLabel           matlab.ui.control.Label
        Channel2Spinner                matlab.ui.control.Spinner
        Channel2SpinnerLabel           matlab.ui.control.Label
        Channel1Spinner                matlab.ui.control.Spinner
        Channel1SpinnerLabel           matlab.ui.control.Label
        ApproxDepthmSpinner            matlab.ui.control.Spinner
        ApproxDepthmSpinnerLabel       matlab.ui.control.Label
        SalinitypsuSpinner             matlab.ui.control.Spinner
        SalinitypsuSpinnerLabel        matlab.ui.control.Label
        TiltCorrectSwitch              matlab.ui.control.Switch
        TiltCorrectSwitchLabel         matlab.ui.control.Label
        OrientationSwitch              matlab.ui.control.Switch
        OrientationSwitchLabel         matlab.ui.control.Label
        NoiseFloorcountsLabel          matlab.ui.control.Label
        EchogramandProfilePlotSettingsPanel  matlab.ui.container.Panel
        AutoCheckBox                   matlab.ui.control.CheckBox
        Value2Plot                     matlab.ui.control.DropDown
        hoursLabel                     matlab.ui.control.Label
        PlotAllDataCheckBox            matlab.ui.control.CheckBox
        PlotOffsetSpinner              matlab.ui.control.Spinner
        PlotOffsetfromStartEndLabel    matlab.ui.control.Label
        PDFoutputsizeLabel             matlab.ui.control.Label
        FigureresolutionDropDown       matlab.ui.control.DropDown
        FigureResolutionLabel          matlab.ui.control.Label
        ColourbardBLabel               matlab.ui.control.Label
        maxSpinner                     matlab.ui.control.Spinner
        maxSpinnerLabel                matlab.ui.control.Label
        minSpinner                     matlab.ui.control.Spinner
        minSpinnerLabel                matlab.ui.control.Label
        PingstoAvgSpinner              matlab.ui.control.Spinner
        PingstoAvgSpinnerLabel         matlab.ui.control.Label
        RangeBinstoAvgSpinner          matlab.ui.control.Spinner
        RangeBinstoAvgSpinnerLabel     matlab.ui.control.Label
        DaystoPlotSpinner              matlab.ui.control.Spinner
        DaystoPlotpersegmentSpinnerLabel  matlab.ui.control.Label
        YLimitmSpinner                 matlab.ui.control.Spinner
        YLimitmSpinnerLabel            matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        RawFileTextLoc2                matlab.ui.control.Label
        EchogramPlotSummaryLabel       matlab.ui.control.Label
        EchoTable                      matlab.ui.control.Table
        SensorTab                      matlab.ui.container.Tab
        SummaryOutputSettingsPanel     matlab.ui.container.Panel
        SensorYLim2_L2                 matlab.ui.control.Spinner
        YLim2Label_2                   matlab.ui.control.Label
        SensorYLim1_L2                 matlab.ui.control.Spinner
        YLim1Label_2                   matlab.ui.control.Label
        RightAxisLabel_2               matlab.ui.control.Label
        LeftAxisLabel_2                matlab.ui.control.Label
        SensorYLim2_R2                 matlab.ui.control.Spinner
        SensorYLim1_R2                 matlab.ui.control.Spinner
        DepthLabel_2                   matlab.ui.control.Label
        TiltLabel_2                    matlab.ui.control.Label
        TempLabel_2                    matlab.ui.control.Label
        BatteryLabel_2                 matlab.ui.control.Label
        Plot2Label                     matlab.ui.control.Label
        DepthCheckBox_R2               matlab.ui.control.CheckBox
        TiltCheckBox_R2                matlab.ui.control.CheckBox
        TempCheckBox_R2                matlab.ui.control.CheckBox
        BatteryCheckBox_R2             matlab.ui.control.CheckBox
        DepthCheckBox_L2               matlab.ui.control.CheckBox
        TiltCheckBox_L2                matlab.ui.control.CheckBox
        TempCheckBox_L2                matlab.ui.control.CheckBox
        BatteryCheckBox_L2             matlab.ui.control.CheckBox
        RightAxisLabel                 matlab.ui.control.Label
        LeftAxisLabel                  matlab.ui.control.Label
        SensorYLim2_R1                 matlab.ui.control.Spinner
        SensorYLim1_R1                 matlab.ui.control.Spinner
        SensorYLim2_L1                 matlab.ui.control.Spinner
        YLim2Label                     matlab.ui.control.Label
        SensorYLim1_L1                 matlab.ui.control.Spinner
        YLim1Label                     matlab.ui.control.Label
        DepthLabel                     matlab.ui.control.Label
        TiltLabel                      matlab.ui.control.Label
        TempLabel                      matlab.ui.control.Label
        BatteryLabel                   matlab.ui.control.Label
        Plot1Label                     matlab.ui.control.Label
        DepthCheckBox_R1               matlab.ui.control.CheckBox
        TiltCheckBox_R1                matlab.ui.control.CheckBox
        TempCheckBox_R1                matlab.ui.control.CheckBox
        BatteryCheckBox_R1             matlab.ui.control.CheckBox
        DepthCheckBox_L1               matlab.ui.control.CheckBox
        TiltCheckBox_L1                matlab.ui.control.CheckBox
        TempCheckBox_L1                matlab.ui.control.CheckBox
        BatteryCheckBox_L1             matlab.ui.control.CheckBox
        PlotSensorDataCheckBox         matlab.ui.control.CheckBox
        StatusBar                      matlab.ui.control.TextArea
        ContextMenu                    matlab.ui.container.ContextMenu
        OpenCHMhelpMenu                matlab.ui.container.Menu
        OpenPDFhelpMenu                matlab.ui.container.Menu
    end

    
    properties (Access = private)
        EchoFigHandle % handle to echogram figure
        ProfileFigHandle % handle to profile figure
        SensorFigHandle % handle to summary sensor plot
        SensorFigHandle2 % handle to sensor figure
        FileSizeFigHandle % handle to filesize figure
        FileStartDate % loaded file start date
        FileEndDate % loaded file end date
        EchoXLim
        EchoXTick
        EchoXTickLabel
        AcquisitionStart %start time of data from DPL file
        hasTemperature %if loaded file has temperature coeff
        ULSFileExt % uls file ext, .01A or uls6 .azfp
        isULS6 % is this uls6 data
    end
    
    methods (Access = private)
        
        % *********************************************************************
        % calc sections start/end to plot based on # daysToPlot
        function CalcEchoSegments(app)
            NumSegs = 5; %number of segments to plot eg. 5x 1 week plots // do not change this
            Data = cell(NumSegs,4);
            Data(:,3) = num2cell(zeros(NumSegs,1));
            Data(:,4) = num2cell(false(NumSegs,1));
            Parameters = app.RawFileTextLoc.UserData;
            Parameters.NumDaysToPlot = app.DaystoPlotSpinner.Value; %num days to plot on echogram
            
            % if NumDaysToPlot = 999, plot start to end (1 line)
            % otherwise divide into 5 segments
            OffsetToPlot = app.PlotOffsetSpinner.Value/24; %offset in hours from first file to plot
            
            % get file start and end dates
            s = strtok(Parameters.FileStart,'.');
            FileStartDate = datenum([s(3:4) '/' s(5:6) '/' s(1:2) ' ' s(7:8) ':00']) + OffsetToPlot;
            if(~isempty(app.AcquisitionStart))
                    if(app.AcquisitionStart - FileStartDate > 1)
                        d = datevec(app.AcquisitionStart);
                        FileStartDate = datenum([d(1:4),0,0]) + OffsetToPlot;
                    end
            end
            s = strtok(Parameters.FileEnd,'.');
            FileEndDate = datenum([s(3:4) '/' s(5:6) '/' s(1:2) ' ' s(7:8) ':00']) - OffsetToPlot;
            Increment = (FileEndDate - FileStartDate)/(NumSegs-1);
            if(FileEndDate < FileStartDate)
                FileEndDate = FileEndDate + OffsetToPlot;
                FileStartDate = FileStartDate - OffsetToPlot;
                NumSegs = 1;
                Increment = 0;
            end
            finished = 0;
            % check for less data than days to plot
            if(Increment < Parameters.NumDaysToPlot)
                NumSegs = ceil((FileEndDate - FileStartDate)/Parameters.NumDaysToPlot);
                Increment = Parameters.NumDaysToPlot;
            end
            if(NumSegs <= 0)
                NumSegs = 1;
            end
            
            for(ii=1:NumSegs)
                if(ii==1)
                    startdate =FileStartDate;
                elseif(ii==NumSegs)
                    startdate = FileEndDate - Parameters.NumDaysToPlot;
                    if(startdate < enddate) % overlaps
                        startdate = enddate;
                    end
                else
                    startdate = round(FileStartDate + Increment*(ii-1));
                end
                enddate = startdate + Parameters.NumDaysToPlot;
                if(enddate > FileEndDate)
                    enddate = FileEndDate - OffsetToPlot;
                    if(enddate < startdate)
                        enddate = FileEndDate;
                    end
                    finished = 1;
                end
                if(startdate == enddate && ii > 1)
                    continue;
                end
                % onlydo 1 segment
                if(app.PlotAllDataCheckBox.Value)
                    enddate = FileEndDate;
                    finished = 1;
                end
                Data(ii,:) = {datestr(startdate,'mmm dd, yyyy HH:MM:SS') datestr(enddate,'mmm dd, yyyy HH:MM:SS') 1 true};
                if(finished)
                    break;
                end
            end
            
            % fill in EchoTable start/end days
            set(app.EchoTable,'data',Data);
            % calc segments to plot ie. 5 week segments by default
            if(app.PlotAllDataCheckBox.Value)
                app.EchoTable.Data{1,3} = ceil((datenum(app.EchoTable.Data{1,2})-datenum(app.EchoTable.Data{1,1}))/app.DaystoPlotSpinner.Value);
            end
        end
        
        % *********************************************************************
        % create weekly echogram plots using the directory info from the Load Data button
        function DoEchograms(app)
            
            fname = {};
            Parameters = app.RawFileTextLoc.UserData;
            Parameters.NumDaysToPlot = app.DaystoPlotSpinner.Value; %num days to plot on echogram                      
            
            % if multifreq then 1 plot for each freq, otherwise if 1 freq do 4 subplots per page
            NumSubPlots = Parameters.NumFreq;
            if Parameters.NumFreq == 1
                NumSubPlots = 4;
            end

            if ~app.hasTemperature
                Parameters(1).HourlyTemp = app.WaterTempSpinner.Value;
            end

            % generate Parameters variable
            Parameters.ProcDir = app.RawFileTextLoc.Text;
            Parameters.datafilename = '';
            Parameters.Salinity = app.SalinitypsuSpinner.Value;
            Parameters.Bins2Avg = app.RangeBinstoAvgSpinner.Value;
            Parameters.Time2Avg = app.PingstoAvgSpinner.Value;
            Parameters.Pressure = app.ApproxDepthmSpinner.Value;
            Parameters.Plot = 1;
            Parameters.Channel = 1;
            Parameters.Value2Plot = app.Value2Plot.Value;
            Parameters.NoiseFloor = [app.Channel1Spinner.Value app.Channel2Spinner.Value app.Channel3Spinner.Value app.Channel4Spinner.Value];
            Parameters.Orientation = double(strcmp(app.OrientationSwitch.Value,'DOWN'));
            Parameters.UseTiltCorr = double(strcmp(app.TiltCorrectSwitch.Value,'On'));
            Parameters.QuickScan = 0;
            ProfileLegend = [];
            Output2 = [];
            
            % get startdate from table
            Data = get(app.EchoTable,'data');
            numPages = 5; % normally 5 segments, unles plotall
            if app.PlotAllDataCheckBox.Value == 1
                numPages = 999;
            end
            % get figure resolution
            dpi = ['-r' strtok(app.FigureresolutionDropDown.Value)];
            
            app.EchoFigHandle = figure;
            set(app.EchoFigHandle,'Position',[20 20 1200 800]);
            app.SensorFigHandle2 = figure('visible','off');
            set(app.SensorFigHandle2,'Position',[20 20 1200 800]);
            app.ProfileFigHandle = figure('visible','off');
            set(app.ProfileFigHandle,'Position',[20 20 1200 800]);
            
            page = 1;
            jj = 1; % single freq subplot location
            PrintNow = 0; % to print in this loop or not
            for ii = 1:numPages
                if page == 19
                    whos
                end
                
                % bring the app figure to the front
                if ii==1
                    pause(0.25);
                    figure(app.figure1);
                    app.figure1.Visible = 'off';app.figure1.Visible = 'on';
                    pause(0.25);
                end

                % plot all weekly
                if numPages == 999
                    if ii==1
                        startdate = datenum(Data{1,1});
                    else
                        startdate = startdate + app.DaystoPlotSpinner.Value;
                    end
                    enddate = startdate + app.DaystoPlotSpinner.Value;
                    if enddate > datenum(Data{1,2})
                        enddate = datenum(Data{1,2});
                    end
                    if startdate > enddate
                        break;
                    end
                else
                    % plot?
                    if ~cell2mat(Data(ii,4))
                        continue;
                    end
                    if isempty(cell2mat(Data(ii,4)))
                        continue;
                    end
                    startdate = datenum(Data{ii,1});
                    enddate = datenum(Data{ii,2});
                end
                
                Output = ExtractAZFP(app,Parameters,startdate,page,sum(cell2mat(Data(:,3))));
                if app.CancelButton.UserData
                    set(app.StatusBar,'value','Plotting Echograms => cancelled');
                    app.PlotButton.Enable = 'on';
                    close(app.EchoFigHandle);
                    close(app.SensorFigHandle2);
                    close(app.ProfileFigHandle);
                    app.CancelButton.Enable = 1;
                    return;
                end
                if jj == 1
                    clf(app.EchoFigHandle);
                    clf(app.SensorFigHandle2);
                end
                
                if ~isempty(Output)
                    % sometime the xml says 4 freq when it is actually 1
                    if size(Output,2) < Parameters.NumFreq
                        Parameters.NumFreq = size(Output,2);
                    end
                    for Channel = 1:Parameters.NumFreq
                        % echogram and profile plots
                        [ProfileLegend,PrintNow] = Plotazfp(app,Output,Parameters,Channel,jj,ProfileLegend);
                    end
                    %if plotall then also plot sensor data
                    if app.PlotSensorDataCheckBox.Value
                        PlotSensor2(app,Output);
                    end
                end
                
                % print now? if 1 freq then it gets complicated
                if Parameters.NumFreq == 1 && jj == NumSubPlots
                    PrintNow = 1;
                elseif Parameters.NumFreq > 1
                    PrintNow = 1;
                elseif ii==numPages % last page
                    PrintNow = 1;
                elseif Parameters.NumFreq == 1 && (page==sum(cell2mat(Data(:,4)))&&sum(cell2mat(Data(:,4))) < 5)
                    PrintNow = 1;
                end
                if ~isempty(Output) && PrintNow
                    pause(5);
                    warning off;
                    set(app.EchoFigHandle,'PaperPosition',[0.05 0.05 10 8]);
                    set(app.EchoFigHandle,'PaperOrientation', 'landscape');
                    set(app.SensorFigHandle2,'PaperPosition',[0.05 0.05 10 4]);
                    set(app.SensorFigHandle2,'PaperOrientation', 'landscape');
                    set(app.ProfileFigHandle,'PaperPosition',[0.05 0.05 8 6]);
                    set(app.ProfileFigHandle,'PaperOrientation', 'landscape');
                    
                    fnameout = [fileparts(mfilename('fullpath')) '\output\' app.SN.Text '_' datestr(Output(1).Date(1),'yyyymmdd')];
                    fname(page,1) = {[app.SN.Text '_' datestr(Output(1).Date(1),'yyyymmdd') '.png']};
                    fname(page,2:3) = {Output(1).Date(1) Output(1).Date(end)};
                    fname(page,4) = {[app.SN.Text '_' datestr(Output(1).Date(1),'yyyymmdd') '_sensor.png']};
                    set(app.EchoFigHandle,'PaperOrientation','portrait');
                    set(app.SensorFigHandle2,'PaperOrientation','portrait');
                    print(app.EchoFigHandle,'-dpng',dpi,fnameout);
                    print(app.SensorFigHandle2,'-dpng',dpi,[fnameout '_sensor']);
                end
                pause(.5);
                warning on;
                Output2 = Output;
                clear Output;
                page = page + 1;
                PrintNow = 0;
                if Parameters.NumFreq == 1 && NumSubPlots == 4
                    jj = jj + 1;
                end
                if jj > NumSubPlots
                    jj = 1;
                end
                if numPages < 999
                    eval(['app.Lamp_' num2str(ii) '.Color=[0 1 0];']);
                end
                if startdate+Parameters.NumDaysToPlot>enddate
                    set(app.StatusBar,'value',['Echograms => Step 2 - Done Segment #' num2str(ii) ' ....saving PDF']);
                    continue;
                end
            end
            %figure(app.figure1);app.figure1.Visible = 'off';app.figure1.Visible = 'on'; %switch back to main app window
            % print profile page
            set(0, 'CurrentFigure', app.ProfileFigHandle);
            % add legend unless too many profiles
            if page < 10
                legend(datestr(cell2mat(ProfileLegend),1));
            end
            set(app.ProfileFigHandle,'PaperOrientation','portrait');
            print(app.ProfileFigHandle,'-dpng',dpi,[fileparts(mfilename('fullpath')) '\output\profiles.png']);
            close(app.EchoFigHandle);
            close(app.SensorFigHandle2);
            close(app.ProfileFigHandle);            
            
            % now do the Latex report output
            if ~isempty(Output2)
                Print_summary_Callback(app,Output2,Parameters,fname);
            end
            cd(app.RawFileTextLoc.Text);
            
            app.Lamp.Color = [0 1 0];
            if numPages == 999
                app.Lamp_1.Color = [0 1 0];
            end
            %set(app.StatusBar,'value',['Echograms => Step 2 Finished! Look for PDF in \DATA directory']);
        end
        
        % *********************************************************************
        function [ProfileLegend,PrintNow] = Plotazfp(app,Output,Parameters,Channel,jj,ProfileLegend)
            
            % only the first datafile's range values are used to plot the data.
            % Plot the data by ping number, not date. If it is burst data (irregularly
            % spaced in time), imagesc will fill the gaps in with the data when plotted vs.
            % Date. Plot as ping number then replace with Dates
            % Echogram and profile plots
            
            PrintNow = 0;

            % switch to Echo fig
            set(0, 'CurrentFigure', app.EchoFigHandle);
            if(Parameters.UseTiltCorr)
                Y = Output(Channel).TiltCorrRange(1,:);
                txt = 'TiltCorr'; % for ylabel
            else
                Y = Output(Channel).Range(1,:);
                txt = ''; % for ylabel
            end
            
            % create a new subplot
            if(Channel == 1 && jj > 1)  
                try
                    subplot(Parameters.NumFreq,1,jj,'replace');
                catch
                    subplot(Parameters.NumFreq,1,1,'replace');
                end
                p1 = get(gca,'position');
                set(gca,'position',[p1(1:2) 0.775 0.15775*1.2]);           
            else
                try
                    subplot(Parameters.NumFreq,1,Channel,'replace');
                catch
                    subplot(Parameters.NumFreq,1,1,'replace');
                end
                if Parameters.NumFreq == 1 && Channel == 1
                    PrintNow = 1;
                end
            end                         
            
            % use Output(1).Depth if non-zero
            if(all(Output(1).Depth) && ~app.isULS6 && app.UsePsensor.Value)
                Xrec = size(Output(1).Date,1);
                Yrec = size(Output(Channel).Sv,2);
                % need to make array from min depth+range(1) to max depth+range(end) and fill
                Y1 = Y(1) + min(Output(1).Depth);
                Y2 = Y(end) + max(Output(1).Depth);
                Ynew = Y1:Output(1).BinInc(Channel):Y2;
                Sv = zeros(Xrec,length(Ynew)) + NaN;
                for(kk=1:Xrec)
                    d = Output(1).Depth(kk)+Y(1);
                    [~,loc]=min(abs(d-Ynew));
                    Sv(kk,loc:loc+Yrec-1) = Output(Channel).Sv(kk,:);
                end
                Output(Channel).Sv = Sv;
                Y = Ynew;
            end
            
            % find loc with N < NoiseFloor
            NFloc = find(Output(Channel).N < Parameters.NoiseFloor(Channel));
            Output(Channel).Sv(NFloc) = NaN;
            imAlpha = 1;
            if(app.Value2Plot.Value == 2)
                imAlpha = ones(size(Output(Channel).Sv'));
                imAlpha(isnan(Output(Channel).Sv')) = 0;
            end
            CBarLim = [app.minSpinner.Value app.maxSpinner.Value];
            
            % what to plot: Sv, counts
            if(app.Value2Plot.Value == 1)
                Z = Output(Channel).N;
                y_label = 'N (counts)';
            else
                Z = Output(Channel).Sv;
                y_label = 'Sv (dB)';
            end
            
            % plot
            imagesc(1:length(Output(1).Date),Y,Z','AlphaData',imAlpha,CBarLim);
            y_tick = [CBarLim(1):(app.maxSpinner.Value-app.minSpinner.Value)/8:CBarLim(2)];
            y_limit = CBarLim;
            colormap(jet(100));
            
            xl=get(gca,'xlim');
            YLim = app.YLimitmSpinner.Value;            
            if(~app.AutoCheckBox.Value)                
                set(gca,'ylim',[0 YLim]);
                if YLim <= 10
                    set(gca,'ytick',[0:1:YLim]);
                end
            end
            if Output(Channel).Freq >= 455 && YLim > 100
                set(gca,'ylim',[0 100]);
            end
            yl=get(gca,'ylim');
            if(Channel==1&&jj==1)
                H = colorbar;
                set(H,'ylim',y_limit);
                set(H,'ytick',y_tick);
                set(H, 'YTickLabel', cellstr(num2str(reshape(get(H, 'YTick'),[],1),'%.0f')) );
                p1 = get(H,'position');
                set(H,'position',[.92 p1(2:4)]);
                title(H,y_label);
            end
            if(Channel==Parameters.NumFreq||jj==Parameters.NumFreq)
                extratxt=[' ' num2str(Output(Channel).Freq) ' kHz'];
                extratxt2 = '';
                xloc=6;
                if(Parameters.NoiseFloor(Channel) > 0 && app.Value2Plot.Value == 2)
                    extratxt2=[', NoiseFloor = ' num2str(Parameters.NoiseFloor(Channel)) ' counts'];
                    xloc=8;
                end
                if(Channel==Parameters.NumFreq)
                    extratxt='';
                    extratxt2='';
                    xloc=4;
                end
                txt2 = ['AZFP #' app.SN.Text extratxt '   [TimeAvg = ' num2str(Output(1).Time2Avg) ' (' sprintf('%d',round(mean(diff(Output(1).Date))*86400)) 's), BinAvg = ' num2str(Output(1).Bins2Avg) ' (' sprintf('%.2f',mean(diff(Output(1).Range(1,:)))) 'm)' extratxt2 ']'];
                if(Parameters.Orientation)
                    text(xl(2)/xloc,yl(2)*1.2,txt2);
                else
                    text(xl(2)/xloc,-yl(2)*.2,txt2);
                end
                
            end
            ylabel([txt 'Range (metres)']);
            if(Parameters.Orientation) % = 0 = UP = normal, = 1 down = reverse
                set(gca,'ydir','reverse');
            else
                set(gca,'ydir','normal');
            end
            ntick=1;
            if(length(Output(1).Date) > 1)
                if(Parameters.NumDaysToPlot <= 1 || app.FileEndDate-app.FileStartDate < 2)
                    ntick=6;
                end
                set(gca,'xlim',[1 length(Output(1).Date)]);
                set(gca,'xtick',[1:round(length(Output(1).Date)/Parameters.NumDaysToPlot/ntick):length(Output(1).Date)]);
            end
            if(length(get(gca,'xtick'))==Parameters.NumDaysToPlot||ntick>1) % add end tickmark
                set(gca,'xtick',unique([get(gca,'xtick') length(Output(1).Date)]));
            end
            if(ntick==1)
                format = 'dd-mmm-yyyy';
            else
                format = 'dd-mmm-yy HH:MM';
            end
            if(length(Output(1).Date) > 1)
                if(Channel==Parameters.NumFreq)
                    if(ntick==1)
                        set(gca, 'XTickLabel', cellstr(datestr(round(Output(1).Date(round(get(gca,'xtick')))),format)));
                    else
                        set(gca, 'XTickLabel', cellstr(datestr(Output(1).Date(round(get(gca,'xtick'))),format)));
                    end
                else
                    set(gca, 'XTickLabel',[]);
                end
            end
            yl=get(gca,'ylim');
            set(gca,'ylim',[0 yl(2)]);
            % set up title
            if(Parameters.NoiseFloor(Channel) > 0 && app.Value2Plot.Value == 2)
                txt2 = ['{\bf' num2str(Output(Channel).Freq) 'kHz, NoiseFloor = ' num2str(Parameters.NoiseFloor(Channel)) ' counts }'];
                if(Parameters.Orientation)
                    text(xl(2)*.7,-yl(2)/20,txt2);
                else
                    text(xl(2)*.7,yl(2)*1.05,txt2);
                end
            else
                txt2 = ['{\bf' num2str(Output(Channel).Freq) 'kHz}'];
                if(Parameters.Orientation)
                    text(xl(2)*.94,-yl(2)/20,txt2);
                else
                    text(xl(2)*.94,yl(2)*1.05,txt2);
                end
            end
            
            set(gca,'fontsize',9);
            
            % save the xlim, xticks and xticklabels for the sensor2 plot
            app.EchoXLim = get(gca,'XLim');
            app.EchoXTick = get(gca,'XTick');
            app.EchoXTickLabel = get(gca,'XTickLabel');
            
            % switch to Profile fig
            set(0, 'CurrentFigure', app.ProfileFigHandle);
            
            % create a new subplot
            if(Channel == 1 && jj > 1)
                subplot(2,2,jj);
            else
                subplot(2,2,Channel);
            end
            
            % plot Output(Channel).N as a function of Output(Channel).Range (Y vs X, or N vs R)
            hold on;
            plot(Output(Channel).Range(1,:),Output(Channel).N(round(length(Output(1).Date)/2),:));
            xlabel([txt 'Range (metres)']);
            XLim = app.YLimitmSpinner.Value;
            if(~app.AutoCheckBox.Value)
                set(gca,'xlim',[0 XLim]);
            end
            title([num2str(Output(Channel).Freq) ' kHz']);
            ylabel('Counts');
            set(gca,'ylim',[0 70000]);
            grid on;
            % save date for legend
            if(Channel==1)
                if(~isempty(ProfileLegend))
                    ProfileLegend = [ProfileLegend Output(1).Date(round(length(Output(1).Date)/2))];
                else
                    ProfileLegend = {Output(1).Date(round(length(Output(1).Date)/2))};
                end
            end
            
            % switch to Echo fig
            set(0, 'CurrentFigure', app.EchoFigHandle);
            
        end
        
        % *********************************************************************
        function Output = ExtractAZFP(app,Parameters,day,CurrentPage,NumPages)
            
            % Enter startdate to plot and numdays to plot
            % Output=ExtractAZFP(Parameters,startdate,numdays)
            % enter parameters in azfp par file and load into workspace and pass into
            % function
            % eg. Output=ExtractAZFP(Parameters,datenum('2016/10/6'),7);
            
            FnameExt = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ'; %for possible fname .01A, .01B etc
            NextExt = 1;
            
            % plot numdays days of data
            dirname = Parameters.ProcDir;
            numfiles2plot = Parameters.NumDaysToPlot*24;
            cd(dirname);
            
            % override parameters passed in
            Parameters.ProcDir = 0;
            Parameters.Plot = 0;
            Output=[];
            if(isempty(day))
                return;
            end
            % calc start dir and filename
            if ~app.isULS6
                Direc = [datestr(day,'yyyy') datestr(day,'mm')];

                % phases .01A, .02A etc
                % need to fix for multi files .01A, .01B etc
                PhaseNum = 1;
                TotPhaseArr = 1:Parameters.NumPhases;
                if(Parameters.NumPhases > 1)
                    % check date and increment phase number
                    PhaseNum = max(TotPhaseArr(day>Parameters.PhaseStart));
                end
                file = [datestr(day,'yy') datestr(day,'mm')  datestr(day,'dd')  datestr(day,'hh') '.0' num2str(PhaseNum) FnameExt(1)];
            else
                Direc = [datestr(day,'yyyy') datestr(day,'mm') datestr(day,'dd')];
                file = [datestr(day,'yy') datestr(day,'mm')  datestr(day,'dd')  datestr(day,'hh') '_0' app.ULS6Phase.Value 'A.azfp'];
            end
            
            try
                cd(Direc);
            catch
                return;
            end
            ii=1;
            row = 1;
            while(ii <= numfiles2plot)%+1)
                if(app.CancelButton.UserData)
                    set(app.StatusBar,'value','Plotting Echograms => cancelled');
                    app.CancelButton.Enable = 1;
                    return;
                end
                Parameters.datafilename = char(file);
                
                % if file doesn't exist then change directory
                if(~exist(Parameters.datafilename,'file'))
                    cd(dirname);
                    if ~app.isULS6
                        Direc = [datestr(day,'yyyy') datestr(day,'mm')];
                    else
                        Direc = [datestr(day,'yyyy') datestr(day,'mm') datestr(day,'dd')];
                    end
                    try
                        cd(Direc);
                        %fprintf('%s\n',Direc);
                    catch
                        return;
                    end
                end
                % missing file? keep going
                try
                    missing = 0;
                    tmp = ProcessAZFP(Parameters);
                    if(isempty(tmp))
                        missing = 1;
                    end
                catch
                    missing = 1;                    
                end

                loadText = 'Loading';
                if(~exist(Parameters.datafilename,'file'))
                    loadText = 'Missing';
                end
                set(app.StatusBar,'value',sprintf('Plotting Echograms (page %d/%d) => %s file: %s',CurrentPage,NumPages,loadText,Parameters.datafilename));
                drawnow;

                if missing && ~app.isULS6
                    if(Parameters.NumPhases > 1)
                        % check date and increment phase number
                        PhaseNum = max(TotPhaseArr(day>Parameters.PhaseStart));
                    end
                    day=day+1/24;
                    ii=ii+1;
                    file = [datestr(day,'yy') datestr(day,'mm')  datestr(day,'dd')  datestr(day,'hh') '.0' num2str(PhaseNum) FnameExt(1)];
                    continue;
                end
                if(size(tmp,2) < Parameters.NumFreq)
                    Parameters.NumFreq = size(tmp,2);
                end
                % catch case where phase change or something has happened and the # of ranges is now different
                if(ii > 1 && ~isempty(Output))
                    try
                        if(size(Output(1).Range,2) ~= size(tmp(1).Range,2))
                            %msgbox(['Ending this segment...ranges are different in file: ' tmp(1).filename],'Message');
                            set(app.StatusBar,'value',['Ending this segment...ranges are different in file: ' tmp(1).filename]);
                            return;
                        end
                    catch
                        fprintf('No range in first channel...skipping file\n');
                        return; % no range in ii=1 loop?
                    end
                end
                Output(1).filename(ii,1) = tmp(1).filename;
                Output(1).HourlyAvgTemp(ii,:) = tmp(1).HourlyAvgTemp;
                Output(1).SoundSpeed(ii,:) = tmp(1).SoundSpeed;
                Output(1).Date(row:row+size(tmp(1).Date,1)-1,1) = tmp(1).Date;
                Output(1).Tx(row:row+size(tmp(1).Tx,1)-1,1) = tmp(1).Tx;
                Output(1).Ty(row:row+size(tmp(1).Ty,1)-1,1) = tmp(1).Ty;
                Output(1).T(row:row+size(tmp(1).T,1)-1,1) = tmp(1).T;
                Output(1).BatteryMain(row:row+size(tmp(1).BatteryMain,1)-1,1) = tmp(1).BatteryMain;
                Output(1).BatteryTx(row:row+size(tmp(1).BatteryTx,1)-1,1) = tmp(1).BatteryTx;
                for(Channel=1:Parameters.NumFreq)
                    Output(Channel).Range(ii,:) = tmp(Channel).Range;
                    Output(Channel).TiltCorrRange(ii,:) = tmp(Channel).TiltCorrRange;
                    Output(Channel).seaAbs(ii,:) = tmp(Channel).seaAbs;
                    Output(Channel).N(row:row+size(tmp(Channel).N,1)-1,:) = tmp(Channel).N;
                    Output(Channel).Sv(row:row+size(tmp(Channel).Sv,1)-1,:) = tmp(Channel).Sv;
                    Output(Channel).Depth(row:row+size(tmp(Channel).Depth,1)-1,:) = tmp(Channel).Depth;
                    %if(ii==1)
                    Output(Channel).Freq = tmp(Channel).Freq;
                    Output(Channel).Bins2Avg = tmp(Channel).Bins2Avg;
                    Output(Channel).BinInc = tmp(Channel).BinInc;
                    Output(Channel).Time2Avg = tmp(Channel).Time2Avg;
                    %end
                end
                row = row + size(tmp(Channel).Sv,1);
                ii=ii+1;
                clear tmp
                
                day=day+1/24;
                if ~app.isULS6
                    if(Parameters.NumPhases > 1)
                        % check date and increment phase number
                        PhaseNum = max(TotPhaseArr(day>Parameters.PhaseStart));
                    end

                    file = [datestr(day,'yy') datestr(day,'mm')  datestr(day,'dd')  datestr(day,'hh') '.0' num2str(PhaseNum) FnameExt(NextExt+1)];
                    if(~exist(file,'file'))
                        NextExt = 1;
                        file(end) = 'A';
                    else
                        NextExt = NextExt + 1;
                    end
                else
                    file = [datestr(day,'yy') datestr(day,'mm')  datestr(day,'dd')  datestr(day,'hh') '_0' app.ULS6Phase.Value 'A.azfp'];
                end
            end
        end
        
        % *********************************************************************
        % set all buttons to defaults values and clear all userdata values
        function DefaultButton(app)
            app.Lamp.Color = [0 1 0];
            app.ULS6Phase.Enable = 1;
            app.Lamp_1.Color = [0.8 0.8 0.8];
            app.Lamp_2.Color = [0.8 0.8 0.8];
            app.Lamp_3.Color = [0.8 0.8 0.8];
            app.Lamp_4.Color = [0.8 0.8 0.8];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            app.PlotButton.Enable = 'off';
            app.Load.Enable = 'on';
            app.Load_2.Enable = 'on';
            app.DaystoPlotSpinner.Enable = 'off';
            app.DaystoPlotpersegmentSpinnerLabel.Enable = 'off';
            app.PlotAllDataCheckBox.Enable = 'off';
            app.PlotOffsetSpinner.Enable = 'off';
            app.PlotOffsetfromStartEndLabel.Enable = 'off';
            app.Value2Plot.Enable = 'on';
            app.CancelButton.UserData = 0;
            app.CancelButton.Enable = 1;
            app.RawFileTextLoc.UserData = [];
            app.RawFileTextLoc.Text = ' Select raw AZFP data directory, example c:\xyz\DATA';
            app.RawFileTextLoc2.Text = ' Raw AZFP data directory (from Summary tab)';
            app.RawFileTextLoc3.Text = ' Raw AZFP data directory (from Summary tab)';            
            set(app.FileSummaryTable,'data',cell(1,9));
            set(app.LogFileOutputTextArea,'Value', '');
            set(app.DeploymentFileTextArea,'Value', '');
            set(app.SummaryTable,'data',cell(5,6));
            set(app.SummaryTable,'userdata',cell(5,6));
            set(app.EchoTable,'data',cell(5,4));
            app.Load.UserData = '';
            set(app.StatusBar,'value','');
            set(app.SN,'text','');
            app.Channel1Spinner.Value = 0;
            app.Channel2Spinner.Value = 0;
            app.Channel3Spinner.Value = 0;
            app.Channel4Spinner.Value = 0;
            app.Channel1Spinner.UserData = '';
            app.FileStartDate = [];
            app.FileEndDate = [];
            app.DaystoPlotSpinner.Limits = [0.5 30];
            app.EchoXLim = [];
            app.EchoXTick = [];
            app.EchoXTickLabel =[];
            app.AcquisitionStart = [];
            app.StartDatefromdplfileTextArea.Value = '';
            app.StartDatefromdplfileTextArea.FontColor = 'k';
            app.StartDatefromdplfileTextArea.BackgroundColor = [0.96,0.89,0.71];
            app.FileSummaryTable.BackgroundColor = [1 1 1; 0.94 0.94 0.94];
            app.FileSummaryTable.FontWeight = 'normal';
            app.FileSummaryTable.ForegroundColor = 'k';
            app.hasTemperature = 1;
            app.WaterTempSpinner.Enable = 0;
            app.WaterTempdegCSpinnerLabel.Enable = 0;
        end
        
        % ****************************************
        % --- Executes once Echograms are plotted
        % output to Latex style report
        function Print_summary_Callback(app,Output,Parameters,fname)
            
            rownames = {'TVR (dB)','VTX (V)','BP (Sr)','Echo Level (dB)','Detector Slope (V/dB)','Calc Avg Absorp Coeff (dB/m)','Calc Avg Sound Speed (m/s)','Noise Floor (counts)'};
            colnames = {'Start','End','Expected','Actual','Missing','Min (kB)','Max (kB)','Avg (kB)','Total (GB)'};
            rownames2 = {'START','30\%','50\%','70\%','END'};
            colnames2 = {'Date','Battery Main (V)','Battery Tx (V)','Tilt-X (deg)','Tilt-Y (deg)','Temp (degC)'};
            rownames3 = {'Segment 1','Segment 2','Segment 3','Segment 4','Segment 5'};
            colnames3 = {'Start Date','End Date','\# Pages','Plot'};
            FS = [];
            LogFileContents = [];
            DeploymentFileContents = [];
            fnameout = [];
            CFGTable = cell(6,4);
            FileTable = cell(1,9);
            % build cfgtable
            for(ii=1:Parameters.NumFreq)
                CFGTable(1,ii)={Parameters.TVR(ii)};
                CFGTable(2,ii)={Parameters.VTX(ii)};
                CFGTable(3,ii)={Parameters.BP(ii)};
                CFGTable(4,ii)={Parameters.EL(ii)};
                CFGTable(5,ii)={Parameters.DS(ii)};
                CFGTable(6,ii)={mean(Output(ii).seaAbs(Output(ii).seaAbs>0))};
                CFGTable(7,ii)={mean(Output(ii).SoundSpeed(Output(ii).SoundSpeed>0))};
            end
            
            set(app.StatusBar,'Value','Saving output to a pdf file...');
            
            try
                % get File Summary data, Log FileOutput and Deployment File
                FS = get(app.FileSummaryTable,'data');
                LogFileContents = get(app.LogFileOutputTextArea,'Value');
                DeploymentFileContents = get(app.DeploymentFileTextArea,'Value');
                
                % filename
                fnameout = [app.SN.Text '_SummaryDataReport_' datestr(now,30) '.tex'];
                
                % generate report latex code
                % use fileparts(mfilename('fullpath')) to save .tex file then move it to outputdir
                % after get(handles.outputdir_summary,'string')
                [fid,message] = fopen([fileparts(mfilename('fullpath')) '\output\' fnameout],'wt');
                if(fid < 0)
                    set(app.StatusBar,'Value',['Problem opening output file: ' message]);
                    return;
                end
                
                % start writing LaTeX code
                fprintf(fid,'%s\n','\documentclass{article}');
                fprintf(fid,'%s\n','\usepackage{graphicx}');
                fprintf(fid,'%s\n','\usepackage{charter}');
                fprintf(fid,'%s\n','\usepackage[table]{xcolor}');
                fprintf(fid,'%s\n','\usepackage[en-US]{datetime2}');
                fprintf(fid,'%s\n','\usepackage[hmargin=.8in,top=0.4in, bottom=\dimexpr0.4in+1.2\baselineskip\relax,includehead, includefoot,headheight=75pt]{geometry}');
                fprintf(fid,'%s\n','\usepackage{array}');
                fprintf(fid,'%s\n','\usepackage{dcolumn,booktabs}');
                fprintf(fid,'%s\n','\usepackage{siunitx}');
                fprintf(fid,'%s\n','\usepackage{fancyhdr,float}');
                fprintf(fid,'%s\n','\usepackage[obeyspaces]{url}');
                fprintf(fid,'%s\n','\usepackage{pdflscape}');
                fprintf(fid,'%s\n','\usepackage{listings}');
                fprintf(fid,'%s\n','\lstset{');
                fprintf(fid,'\t%s\n','breaklines=true,');
                fprintf(fid,'\t%s\n','basicstyle=\ttfamily}');
                fprintf(fid,'%s\n','\graphicspath{{../images/}}');
                fprintf(fid,'%s\n','\pagestyle{fancy}');
                fprintf(fid,'%s\n','\newcolumntype{d}[1]{D{.}{.}{4}}');
                fprintf(fid,'%s\n','\newcommand\mc[1]{\multicolumn{1}{c}{#1}}'); % handy shortcut macro
                fprintf(fid,'%s\n','\DTMlangsetup[en-US]{monthyearsep={,\space}}');
                fprintf(fid,'%s\n','\fancypagestyle{nohf}{');% no header/footer
                fprintf(fid,'%s\n','\fancyhf{}'); % clear all header and footer fields
                fprintf(fid,'%s\n','\fancyfoot[R]{\thepage}');
                fprintf(fid,'%s\n','\renewcommand{\headrulewidth}{0pt} ');% remove line between header and main text
                fprintf(fid,'%s\n','}');
                fprintf(fid,'%s\n','\fancypagestyle{plain}{'); %with header/footer
                fprintf(fid,'%s\n','\fancyhf{}');
                fprintf(fid,'%s\n','\renewcommand{\headrulewidth}{.4pt}');
                fprintf(fid,'%s\n','\renewcommand{\footrulewidth}{0pt}');
                fprintf(fid,'%s\n','\fancyhead[L]{\includegraphics[width=6cm]{ASLhead.png}}');
                fprintf(fid,'%s\n',['\fancyhead[R]{' app.figure1.Name ' for AZFP s/n: ' app.SN.Text '}']);
                fprintf(fid,'%s\n','\fancyfoot[L]{\includegraphics[width=17cm]{ASLfoot.png}}');
                fprintf(fid,'%s\n','\fancyfoot[R]{\thepage}');
                fprintf(fid,'%s\n','}');
                fprintf(fid,'%s\n','\pagestyle{plain}');
                fprintf(fid,'%s\n','\begin{document}');
                
                fprintf(fid,'%s\n',['\title{\vspace{-1.0cm}\bf AZFP QuickView Utility s/n ' app.SN.Text '}']);
                fprintf(fid,'%s\n','\author{ASL Environmental Sciences, Inc.}');
                fprintf(fid,'%s\n','\date{\today}');
                fprintf(fid,'%s\n','\maketitle');
                
                % summary
                fprintf(fid,'%s\n','\vspace*{-1.05cm} ');
                fprintf(fid,'%s\n','\section{Summary}');
                if app.isULS6
                    fprintf(fid,'%s\n',['\textbf{ULS6 data: plotting Phase ' app.ULS6Phase.Value ' only\\\\}']);
                end
                fprintf(fid,'%s\n',['\textbf{Raw Data:\\} \footnotesize \path{' Parameters.ProcDir '}\\\\']);
                fprintf(fid,'%s\n',['\normalsize \textbf{XML:\\} \footnotesize \path{' Parameters.xmlpathname '\' Parameters.xmlfilename '}\\\\']);
                if(~isempty(app.Channel1Spinner.UserData))
                    fprintf(fid,'%s\n',['\normalsize \textbf{Noise Floor:\\} \footnotesize \path{' app.Channel1Spinner.UserData '}\\']);
                else
                    fprintf(fid,'%s\n','\normalsize \textbf{Noise Floor:\\} \footnotesize {xlsx file not found...using defaults}\\');
                end
                fprintf(fid,'%s\n','\setlength{\arrayrulewidth}{.3mm}');
                fprintf(fid,'%s\n','\renewcommand{\arraystretch}{1.75}');
                fprintf(fid,'%s\n','\setlength\tabcolsep{5pt}');
                fprintf(fid,'%s\n','\vspace*{-0.5cm} ');                
                fprintf(fid,'%s\n','\begin{table}[h]');
                fprintf(fid,'%s\n','\begin{center}');
                fprintf(fid,'%s\n','\caption{File Summary}');
                fprintf(fid,'%s\n','\begin{tabular}{|m{10.2em}|m{10.2em}|m{4em}|m{3em}|m{4em}|m{3em}|m{3em}|m{3em}|m{4em}|}');
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s\n','\multicolumn{2}{|c}{\textbf{Date}}& \multicolumn{3}{|c}{\textbf{Number of Files}} & \multicolumn{4}{|c|}{\textbf{File Size}}\\');
                fprintf(fid,'%s\n','\hline');
                for(ii=1:length(FS))
                    fprintf(fid,'%s',['\textbf{' cell2mat(colnames(ii)) '}']);
                    if(ii<length(FS))
                        fprintf(fid,'%s','&');
                    end
                end
                fprintf(fid,'%s\n','\\');
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s\n','\rowcolor{black!50!white!40}');
                % cfg table data
                for(ii=1:length(FS)) %column
                    if(ii<3)
                        fprintf(fid,'%s\n',cell2mat(FS(1,ii)));
                    elseif(ii < 6)
                        fprintf(fid,'%d\n',cell2mat(FS(1,ii)));
                    else
                        fprintf(fid,'%.2f\n',str2double(cell2mat(FS(1,ii))));
                    end
                    if(ii<length(FS))
                        fprintf(fid,'%s','&');
                    end
                end
                fprintf(fid,'%s\n','\\');
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s\n','\end{tabular}');
                fprintf(fid,'%s\n','\end{center}');
                fprintf(fid,'%s\n','\end{table}');

                % filesize figure
                fprintf(fid,'%s\n','\begin{figure}[!h]');
                fprintf(fid,'%s\n','\hspace*{-2cm} ');
                fprintf(fid,'%s\n','\includegraphics[width=8.5in]{FSsummary.png}');
                fprintf(fid,'%s\n','\caption{File Size Summary}');
                fprintf(fid,'%s\n','\label{Filesizefigure}');
                fprintf(fid,'%s\n','\end{figure}');
                fprintf(fid,'%s\n','\clearpage');

                fprintf(fid,'%s\n','{\rowcolors{2}{black!0!white!0}{black!50!white!40}');
                fprintf(fid,'%s\n','\setlength\tabcolsep{12pt}');
                fprintf(fid,'%s\n','\vspace*{-0.6cm} ');
                fprintf(fid,'%s\n','\begin{table}[h]');
                fprintf(fid,'%s\n','\begin{center}');
                fprintf(fid,'%s\n','\caption{Instrument CFG values and calculated Absorption Coeff and Sound Speed}');
                fprintf(fid,'%s\n',['\begin{tabular}{ |l|' repmat('p{4.5em}|',1,length(Output)) '  }']);
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s','&');
                for(ii=1:length(Output))
                    fprintf(fid,'%s',['\textbf{' num2str(Output(ii).Freq) 'kHz}']);
                    if(ii<length(Output))
                        fprintf(fid,'%s','&');
                    end
                end
                fprintf(fid,'%s\n','\\');
                fprintf(fid,'%s\n','\hline');
                % cfg table data
                for(jj=1:length(rownames))% rows
                    fprintf(fid,'%s',['\textbf{' rownames{jj} '}&']);
                    for(ii=1:length(Output)) %columns
                        if(jj < 7)
                            fprintf(fid,'%.4f\n',cell2mat(CFGTable(jj,ii)));
                        elseif(jj == 8)
                            t = 0;
                            %if(~isempty(app.Channel1Spinner.UserData))
                                eval(['t = app.Channel' num2str(ii) 'Spinner.Value;']);
                            %end
                            fprintf(fid,'%d\n',t);
                        else
                            if(ii==1)
                                fprintf(fid,'%.1f\n',cell2mat(CFGTable(jj,ii)));
                            end
                        end
                        if(ii<length(Output))
                            fprintf(fid,'%s','&');
                        end
                    end
                    fprintf(fid,'%s\n','\\');
                    fprintf(fid,'%s\n','\hline');
                    
                end
                fprintf(fid,'%s\n','\end{tabular}');
                fprintf(fid,'%s\n','\end{center}');
                fprintf(fid,'%s\n','\end{table}');
                fprintf(fid,'%s\n','}');
                
                % print out log file text
                fprintf(fid,'%s\n','\section{Log File Contents:}');
                fprintf(fid,'%s\n','\begin{lstlisting}');
                for ii=1:length(LogFileContents)
                    fprintf(fid,'%s \n',char(LogFileContents(ii)));
                    if ii > 50
                        fprintf(fid,'\n \textbf{*** %d lines of Log File contents not shown ***}\n',length(LogFileContents)-ii);
                        break;
                    end
                end
                fprintf(fid,'\n%s\n','\end{lstlisting}');
                % print out deploy file text
                fprintf(fid,'%s\n','\section{Deployment File:}');
                fprintf(fid,'%s\n','\fontsize{8}{8}');
                if(isempty(char(DeploymentFileContents(1))))
                    fprintf(fid,'\\texttt{%s} %s \n','DPL file not found!','\\');
                end
                fprintf(fid,'%s\n','\begin{lstlisting}');
                for(ii=1:length(DeploymentFileContents))
                    fprintf(fid,'%s \n',char(DeploymentFileContents(ii)));
                end
                fprintf(fid,'\n%s\n','\end{lstlisting}');
                fprintf(fid,'%s\n','\selectfont');
                fprintf(fid,'%s\n','\fontsize{10}{12}');
                % Sensor data
                %fprintf(fid,'%s\n','\clearpage');
                fprintf(fid,'%s\n','\section{Sensor Summary}');
                fprintf(fid,'%s\n','\setlength{\arrayrulewidth}{.3mm}');
                fprintf(fid,'%s\n','\renewcommand{\arraystretch}{1.75}');
                fprintf(fid,'%s\n','\setlength\tabcolsep{12pt}');
                fprintf(fid,'%s\n','{\rowcolors{2}{black!0!white!0}{black!50!white!40}');
                fprintf(fid,'%s\n','\begin{table}[!h]');
                fprintf(fid,'%s\n','\begin{center}');
                fprintf(fid,'%s\n','\caption{Monthly Sensor Summary}');
                fprintf(fid,'%s\n','\begin{tabular}{|m{3em}|m{11em}|m{4em}|m{4em}|m{3em}|m{3em}|m{3em}|}');
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s','&');
                for(ii=1:length(colnames2))
                    fprintf(fid,'%s',['\textbf{' cell2mat(colnames2(ii)) '}']);
                    if(ii<length(colnames2))
                        fprintf(fid,'%s','&');
                    end
                end
                fprintf(fid,'%s\n%s\n','\\','\hline');
                % cfg table data
                for(jj=1:size(app.SummaryTable.Data,1))% rows
                    fprintf(fid,'%s',['\textbf{' rownames2{jj} '}&']);
                    for(ii=1:size(app.SummaryTable.Data,2)) %columns
                        if(ii==1)
                            fprintf(fid,'%s\n',cell2mat(app.SummaryTable.Data(jj,ii)));
                        else
                            if(~isempty(cell2mat(app.SummaryTable.Data(jj,ii))))
                                fprintf(fid,'%.2f\n',str2double(cell2mat(app.SummaryTable.Data(jj,ii))));
                            end
                        end
                        if(ii<size(app.SummaryTable.Data,2))
                            fprintf(fid,'%s','&');
                        end
                    end
                    fprintf(fid,'%s\n','\\');
                    fprintf(fid,'%s\n','\hline');
                end
                fprintf(fid,'%s\n','\end{tabular}');
                fprintf(fid,'%s\n','\end{center}');
                fprintf(fid,'%s\n','\end{table}');
                % sensor figure
                fprintf(fid,'%s\n','\begin{figure}[!h]');
                fprintf(fid,'%s\n','\centering');
                fprintf(fid,'%s\n','\includegraphics[width=6.5in]{summary.png}');
                fprintf(fid,'%s\n','\caption{Sensor Data Summary}');
                fprintf(fid,'%s\n','\label{Summaryfigure}');
                fprintf(fid,'%s\n','\end{figure}');
                
                % Echograms
                fprintf(fid,'%s\n','\section{Echogram Plot Summary}');
                fprintf(fid,'%s\n','\setlength{\arrayrulewidth}{.3mm}');
                fprintf(fid,'%s\n','\renewcommand{\arraystretch}{1.75}');
                fprintf(fid,'%s\n','\setlength\tabcolsep{12pt}');
                fprintf(fid,'%s\n','{\rowcolors{2}{black!0!white!0}{black!50!white!40}');
                fprintf(fid,'%s\n','\begin{table}[h]');
                fprintf(fid,'%s\n','\begin{center}');
                fprintf(fid,'%s\n','\caption{Echogram Plot Summary}');
                fprintf(fid,'%s\n','\begin{tabular}{|m{5em}|m{11em}|m{11em}|m{5em}|m{3em}|}');
                fprintf(fid,'%s\n','\hline');
                fprintf(fid,'%s','&');
                for(ii=1:length(colnames3))
                    fprintf(fid,'%s',['\textbf{' cell2mat(colnames3(ii)) '}']);
                    if(ii<length(colnames3))
                        fprintf(fid,'%s','&');
                    end
                end
                fprintf(fid,'%s\n%s\n','\\','\hline');
                % cfg table data
                for(jj=1:size(app.EchoTable.Data,1))% rows
                    fprintf(fid,'%s',['\textbf{' rownames3{jj} '}&']);
                    for(ii=1:size(app.EchoTable.Data,2)) %columns
                        if(ii < 3)
                            fprintf(fid,'%s\n',cell2mat(app.EchoTable.Data(jj,ii)));
                        elseif(ii == 3)
                            fprintf(fid,'%d\n',cell2mat(app.EchoTable.Data(jj,ii)));
                        else
                            showplot = 'n';
                            if(cell2mat(app.EchoTable.Data(jj,ii)))
                                showplot = 'y';
                            end
                            fprintf(fid,'%s\n',showplot);
                        end
                        if(ii < size(app.EchoTable.Data,2))
                            fprintf(fid,'%s','&');
                        end
                    end
                    fprintf(fid,'%s\n','\\');
                    fprintf(fid,'%s\n','\hline');
                end
                fprintf(fid,'%s\n','\end{tabular}');
                fprintf(fid,'%s\n','\end{center}');
                fprintf(fid,'%s\n','\end{table}');
                fprintf(fid,'%s %s\n','\textbf{Echogram Plot settings:}','\\');
                fprintf(fid,'%s\n','\begin{itemize}');
                fprintf(fid,'%s\n',['\item \# Days to plot per segment: ' num2str(Parameters.NumDaysToPlot)]);
                fprintf(fid,'%s\n',['\item Plot All Data: ' num2str(app.PlotAllDataCheckBox.Value)]);
                fprintf(fid,'%s\n',['\item \# Range Bins to Average: ' num2str(Parameters.Bins2Avg)]);
                fprintf(fid,'%s\n',['\item \# Time Values to Average: ' num2str(Parameters.Time2Avg)]);
                fprintf(fid,'%s\n',['\item Plot Offset (hours): ' num2str(app.PlotOffsetSpinner.Value)]);
                fprintf(fid,'%s\n',['\item Salinity (psu): ' num2str(Parameters.Salinity)]);
                if(~app.hasTemperature)
                    fprintf(fid,'%s\n',['\item WaterTemp (degC): ' num2str(app.WaterTempSpinner.Value)]);
                end
                fprintf(fid,'%s\n',['\item Approx Depth (m): ' num2str(Parameters.Pressure)]);
                fprintf(fid,'%s\n',['\item Noise Floor (counts): ' num2str(Parameters.NoiseFloor)]);
                fprintf(fid,'%s\n','\end{itemize}');
                
                % landscape or portrait
                if(~app.PlotSensorDataCheckBox.Value)
                    fprintf(fid,'%s\n','\begin{landscape}');
                    echoplotsize = 9.3;
                    profplotsize = 8.1;
                    vspace1 = 3;
                    vspace2 = 3;
                else
                    echoplotsize = 7.5;
                    profplotsize = 7.5;
                    vspace1 = -3;
                    vspace2 = -0.7;
                end
                for(kk=1:size(fname,1))
                    if(~isempty(cell2mat(fname(kk,2))))
                        fprintf(fid,'%s\n','\clearpage');
                        
                        if(app.PlotSensorDataCheckBox.Value)
                            fprintf(fid,'%s\n','\pagestyle{nohf}');
                            fprintf(fid,'%s\n','\setlength{\intextsep}{0pt plus 2pt}');
                            fprintf(fid,'\\vspace*{%.1fcm} \n',vspace1);
                            fprintf(fid,'%s\n','\begin{figure}[H]');
                            fprintf(fid,'\\hspace*{%.1fcm} \n',-1.4);
                            fprintf(fid,'\\includegraphics[width=%.1fin]{%s}\n',profplotsize,char(fname(kk,4)));
                            fprintf(fid,'%s\n',['\label{Sensorfigure' num2str(kk) '}']);
                            fprintf(fid,'\\vspace*{%.1fcm} \n',vspace2);
                            fprintf(fid,'\\hspace*{%.2fcm} \n',-1.45);
                            fprintf(fid,'\\includegraphics[width=%.1fin]{%s}\n',echoplotsize,char(fname(kk,1)));
                            fprintf(fid,'%s\n',['\caption{Echogram \#' num2str(kk) ': ' datestr(cell2mat(fname(kk,2)),'mmm dd, yyyy HH:MM') ' to ' datestr(cell2mat(fname(kk,3)),'mmm dd, yyyy HH:MM') '}']);
                            fprintf(fid,'%s\n',['\label{Echofigure' num2str(kk) '}']);
                            fprintf(fid,'%s\n','\end{figure}');
                        else
                            fprintf(fid,'%s\n','\begin{figure}[!ht]');
                            fprintf(fid,'\\hspace*{%.1fcm} \n',-2.2);
                            fprintf(fid,'\\vspace*{%.1fcm} \n',-1);%1.4
                            fprintf(fid,'\\includegraphics[width=%.1fin]{%s}\n',echoplotsize,char(fname(kk,1)));
                            fprintf(fid,'%s\n',['\caption{Echogram \#' num2str(kk) ': ' datestr(cell2mat(fname(kk,2)),'mmm dd, yyyy HH:MM') ' to ' datestr(cell2mat(fname(kk,3)),'mmm dd, yyyy HH:MM') '}']);
                            fprintf(fid,'%s\n',['\label{Echofigure' num2str(kk) '}']);
                            fprintf(fid,'%s\n','\end{figure}');
                        end
                    end
                end
                
                % Profile plots
                fprintf(fid,'%s\n','\clearpage');
                if(app.PlotSensorDataCheckBox.Value)
                    fprintf(fid,'%s\n','\pagestyle{plain}');
                end
                fprintf(fid,'%s\n','\section{Profiles}');
                fprintf(fid,'%s\n','\begin{figure}[!ht]');
                fprintf(fid,'\\hspace*{%.1fcm} \n',0);
                fprintf(fid,'\\includegraphics[width=%.1fin]{profiles.png}\n',profplotsize);
                fprintf(fid,'%s\n','\caption{Profile plots for each frequency and all segments}');
                fprintf(fid,'%s\n','\label{Profilefigure}');
                fprintf(fid,'%s\n','\end{figure}');
                if(~app.PlotSensorDataCheckBox.Value)
                    fprintf(fid,'%s\n','\end{landscape}');
                end
                fprintf(fid,'%s\n','\selectfont');
                
            catch
                fprintf(fid,'%s\n','ERROR writing values');
                set(app.StatusBar,'Value','ERROR: Install MiKTeX to generate reports!');
            end
            fprintf(fid,'%s\n','\end{document}');
            fclose(fid);
            
            % save to pdf
            cd([fileparts(mfilename('fullpath')) '\output']);
            savecommand = ['pdflatex ' fnameout ' -interaction=nonstopmode'];
            [~, cmdout] = system(savecommand);
            % check for miktex not installed error
            if(contains(cmdout,'is not recognized as an internal'))
                set(app.StatusBar,'Value','ERROR: Install MiKTeX to generate reports!');
            end
            
            pdffile = [fnameout(1:end-4) '.pdf'];
            
            % move pdf to output dir
            try
                movefile([fileparts(mfilename('fullpath')) '\output\' pdffile],[Parameters.ProcDir '\' pdffile],'f');
            catch
                set(app.StatusBar,'Value',['Error writing pdf file: ' cmdout]);
                cd(Parameters.ProcDir);
                return;
            end
            
            % remove .tex, .log, .aux file
            RemoveTexFiles = 1;
            try
                [~,name]= unix('hostname');
                if(strcmp(deblank(name),'DBW102019'))
                    RemoveTexFiles = 0;
                end
            catch
            end
            if(RemoveTexFiles)
                warning off;
                delete(fnameout);
                delete([fnameout(1:end-4) '.log']);
                delete([fnameout(1:end-4) '.aux']);
                warning on;
            end
            
            % open pdf in \DATA output dir
            openpdf([Parameters.ProcDir '/' pdffile]);
            cd(Parameters.ProcDir);
            set(app.StatusBar,'Value',['Output to: ' Parameters.ProcDir '\' pdffile]);
            
        end
        
        function PlotSensor(app,Folders,Stats3,fig)
            if(fig)
                h = gca;
            else
                h = app.axes1;
            end
            yyaxis(h,'left');
            p1=plot(h,cell2mat(Stats3(1:length(Folders),1)),cell2mat(Stats3(1:length(Folders),2)),'o-',cell2mat(Stats3(1:length(Folders),1)),cell2mat(Stats3(1:length(Folders),3)),'s-');
            set(h,'ylim',[8 16]);
            set(p1,'linewidth',1.5);
            set(p1,'markerfacecolor','b');
            ylabel(h,'Battery (V)');
            yyaxis(h,'right');
            p2=plot(h,cell2mat(Stats3(1:length(Folders),1)),cell2mat(Stats3(1:length(Folders),4)),'o-m',cell2mat(Stats3(1:length(Folders),1)),cell2mat(Stats3(1:length(Folders),5)),'s-m');
            set(h,'ylim',[-40 40]);
            set(p2,'linewidth',1.0);
            ylabel(h,'Tilt (deg)');
            datetick(h,'x','mmm');
            grid(h,'on');
            set(h,'fontsize',12);
            % add legend
            legend(h,{'BatteryMain (V)','BatteryTx (V)' 'Tilt-X (deg)','Tilt-Y (deg)'},'location','southwest');
            
        end
        
        function NF = LoadNF(app)
            
            %defaults
            app.Channel1Spinner.Value = 0;
            app.Channel2Spinner.Value = 0;
            app.Channel3Spinner.Value = 0;
            app.Channel4Spinner.Value = 0;
            app.Channel1Spinner.UserData = '';
            NF = [NaN NaN NaN NaN];
            try
                basedir = '\\Nas1\nas_man\Manufacturing Tests\AZFP5\';
                if(str2double(app.SN.Text) > 59000)
                    basedir = '\\Nas1\nas_man\Manufacturing Tests\GLIDER5\';
                end
                d = dir([basedir app.SN.Text '\**/*.xlsx']);
                [~,idx] = sort([d.datenum],'descend');
                d = d(idx);
                for(ii = 1:length(d))                                        
                    fprintf('Loaded NF from: %s\n',[d(ii).folder '\' d(ii).name]);
                    [~,SheetNames] = xlsfinfo([d(ii).folder '\' d(ii).name])
                    try
                        for(jj=1:length(SheetNames)-1)
                            Line = readtable([d(ii).folder '\' d(ii).name],'sheet',SheetNames{jj+1},'Range','D16:D16','ReadVariableNames',false);
                            NF(1,jj) = Line.Var1;
                            if(~isnan(NF(1,1)))
                                eval(['app.Channel' num2str(jj) 'Spinner.Value = NF(1,jj);']);
                                set(app.StatusBar,'value',sprintf('Loading NoiseFloor: %s\n',[d(ii).folder '\' d(ii).name]));
                                app.Channel1Spinner.UserData = [d(ii).folder '\' d(ii).name];                                
                            end
                        end
                        if(~isnan(NF(1,1)))
                            break;
                        end
                    catch
                        % unreadable xlsx
                    end
                end
            catch
                app.Channel1Spinner.Value = 0;
                app.Channel2Spinner.Value = 0;
                app.Channel3Spinner.Value = 0;
                app.Channel4Spinner.Value = 0;
                app.Channel1Spinner.UserData = '';
            end
        end
        
        function SetSpinnerLimits(app,Min,Max,Step,Limits,Txt,Enable)
            
            app.NoiseFloorcountsLabel.Enable = Enable;
            app.Channel1Spinner.Enable = Enable;
            app.Channel1SpinnerLabel.Enable = Enable;
            app.Channel2Spinner.Enable = Enable;
            app.Channel2SpinnerLabel.Enable = Enable;
            app.Channel3Spinner.Enable = Enable;
            app.Channel3SpinnerLabel.Enable = Enable;
            app.Channel4Spinner.Enable = Enable;
            app.Channel4SpinnerLabel.Enable = Enable;
            app.maxSpinner.Limits = Limits;
            app.maxSpinner.Step = Step;
            app.maxSpinner.Value = Max;
            app.ColourbardBLabel.Text = Txt;
            app.minSpinner.Limits = Limits;
            app.minSpinner.Step = Step;
            app.minSpinner.Value = Min;
        end
        
        function PlotSensor2(app,Output)
            
            % make array of plot settings
            Settings = zeros(6,4);
            Settings(1:4,1) = [app.BatteryCheckBox_L1.Value;app.TempCheckBox_L1.Value;app.TiltCheckBox_L1.Value;app.DepthCheckBox_L1.Value];
            Settings(1:4,2) = [app.BatteryCheckBox_R1.Value;app.TempCheckBox_R1.Value;app.TiltCheckBox_R1.Value;app.DepthCheckBox_R1.Value];
            Settings(1:4,3) = [app.BatteryCheckBox_L2.Value;app.TempCheckBox_L2.Value;app.TiltCheckBox_L2.Value;app.DepthCheckBox_L2.Value];
            Settings(1:4,4) = [app.BatteryCheckBox_R2.Value;app.TempCheckBox_R2.Value;app.TiltCheckBox_R2.Value;app.DepthCheckBox_R2.Value];
            Settings(5,:) = [app.SensorYLim1_L1.Value app.SensorYLim1_R1.Value app.SensorYLim1_L2.Value app.SensorYLim1_R2.Value];
            Settings(6,:) = [app.SensorYLim2_L1.Value app.SensorYLim2_R1.Value app.SensorYLim2_L2.Value app.SensorYLim2_R2.Value];
            
            NumPlots = any(Settings(1:4,1:2),'all') + any(Settings(1:4,3:4),'all');
            % switch to Sensor fig
            set(0, 'CurrentFigure', app.SensorFigHandle2);
            
            colororder({'b','m'}); % for left/right axis
            Col = 1;
            % for suplot 1/2
            for(ii = 1:NumPlots)
                Leg = {};
                h = subplot(NumPlots,1,ii);
                
                for(jj = 1:2) % for left/right
                    % plot Batt-main + Tx and Tilt/Press/Temp
                    if(jj == 1)
                        yyaxis(h,'left');
                    else
                        yyaxis(h,'right');
                        if(sum(Settings(1:4,Col+1))==0) % no right plot
                            yticks(h,[]);
                        end
                    end
                    
                    % symbols or not
                    sym1 = 'o';
                    sym2 = 's';
                    sym3 = 'p';
                    sym4 = 'v';
                    if(length(Output(1).Date) > 20)
                        sym1 = '';
                        sym2 = '';
                        sym3 = '';
                        sym4 = '';
                    end
                    hold(h,'on');
                    
                    % battery
                    if(Settings(1,Col+jj-1))
                        p1 = plot(h,1:length(Output(1).Date),Output(1).BatteryMain,[sym1 '--'],1:length(Output(1).Date),Output(1).BatteryTx,[sym1 '-']);
                        set(p1,'linewidth',1);
                        ylabel(h,'Battery (V)');
                        Leg = {'BatteryMain (V)','BatteryTx (V)'};
                        set(p1,'markersize',4);
                        set(p1,'Clipping','off');
                    end
                    
                    % temperature
                    if(Settings(2,Col+jj-1))
                        p2 = plot(h,1:length(Output(1).Date),Output(1).T,[sym2 '-.']);
                        set(p2,'linewidth',1.1);
                        set(p2,'markersize',4);
                        set(p2, 'MarkerFaceColor', get(h,'Color'));
                        set(p2,'Clipping','off');
                        ylabel(h,[h.YLabel.String '   Temp (degC)']);
                        Leg = [Leg, {'Temp (degC)'}];
                    end
                    
                    % tilt
                    if(Settings(3,Col+jj-1))
                        p3 = plot(h,1:length(Output(1).Date),Output(1).Tx,[sym3 ':'],1:length(Output(1).Date),Output(1).Ty,[sym3 '-']);
                        set(p3,'linewidth',1.3);
                        set(p3,'markersize',3);
                        ylabel(h,[h.YLabel.String '   Tilt (deg)']);
                        set(p3,'Clipping','off');
                        Leg = [Leg, {'Tilt-X (deg)','Tilt-Y (deg)'}];
                    end
                    
                    % depth
                    try
                        if(Settings(4,Col+jj-1))
                            p4 = plot(h,1:length(Output(1).Date),Output(1).Depth,[sym4 '-.']);
                            set(p4,'linewidth',1.1);
                            set(p4,'markersize',3);
                            set(p4, 'MarkerFaceColor', get(h,'Color'));
                            ylabel(h,[h.YLabel.String '   Depth (m)']);
                            set(p4,'Clipping','off');
                            Leg = [Leg, {'Depth (m)'}];
                        end
                    catch end
                    try
                        set(h,'ylim',[Settings(5,Col+jj-1) Settings(6,Col+jj-1)]);
                    catch exception
                        set(app.StatusBar,'value',sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message));
                    end
                end
                grid(h,'on');
                set(h,'fontsize',9);

                % set xlim and xticks the same as Echo plot, empty labels
                % except for last plot
                if(~isempty(app.EchoXLim))
                    set(h,'XLim',app.EchoXLim);
                    set(h,'XTick',app.EchoXTick);
                    set(h,'XTickLabels',[]);
                end
                
                hold(h,'off');
                % add legend
                legend(h,Leg,'location','southwest');
                Col = Col + 2; % for column ref to Settings array
            end
            
            % set xlim and xticks the same as Echo plot
            if(~isempty(app.EchoXLim))
                set(h,'XTickLabels',app.EchoXTickLabel);
            end
            
            % switch to Echo fig
            set(0, 'CurrentFigure', app.EchoFigHandle);
            
        end
        
        function PlotFileSize(app,fig)
            
            loc1 = [];
            Ignored = [];            
            Missing = [];

            if(fig)
                h = gca;
            else
                h = app.FileSizeAxes;
            end

            allFiles = dir(['**\' app.ULSFileExt]); % '*.0*.'
            [~,Filenames] = fileparts({allFiles.name});
            A = [datenum(Filenames,'yymmddhh') cell2mat({allFiles.bytes})'/1e6];
            B = sortrows(A,1);
            B(B(:,1) > now,:) = [];

            % keep track of data < DPL acq start
            if(~isempty(app.AcquisitionStart))
                d = datevec(app.AcquisitionStart);
                d = datenum([d(1:4),0,0]);
                loc1 = B(:,1) < d;
                Ignored = B(loc1,:);
                B(B(:,1) < d,:) = [];
            end

            % create an array of the full data hourly to plot missing values
            Hourly = [B(1,1):1/24:B(end,1)]';
            tol = 1e4; % tolerance 1e-4
            Missing = setdiff(round(Hourly*tol)/tol,round(B(:,1)*tol)/tol);
            Missing = [Missing zeros(length(Missing),1)];

            p1 = plot(h,B(:,1),B(:,2),'o-','DisplayName','Used');
            hold(h,'on');
            set(p1,'linewidth',1.2);
            set(p1,'markerfacecolor','b');   
            set(p1,'markersize',3); 
        
            if(~isempty(Ignored))
                p2 = plot(h,Ignored(:,1),Ignored(:,2),'o-r','DisplayName','Ignored');
                set(p2,'linewidth',1);
                set(p2,'markerfacecolor','r');
                set(p2,'markersize',3);                
            end

            if(~isempty(Missing))
                p3 = plot(h,Missing(:,1),Missing(:,2),'xm','DisplayName','Missing');
                set(p3,'markersize',5);               
            end

            ylabel(h,'File size (MB)');
            try
                set(h,'xlim',[B(1,1) B(end,1)]);
                format = 'mmm/yy';
                if(B(end,1)-B(1,1) < 50)
                    format = 'mmmdd';
                end
                datetick(h,'x',format);
            catch end
            grid(h,'on');
            hold(h,'off');
            set(h,'fontsize',12);
            legend(h,'Orientation','horizontal','Location','northwest');
        end
        
        function thePath = ShowPath(app)
            % Show EXE path:
            if isdeployed % Stand-alone mode.
                [~, result] = system('set PATH');
                thePath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % Running from MATLAB.
                thePath=pwd;
            end        
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function azfpdiag_OpeningFcn(app)
            % This function has no output args, see OutputFcn.
            
            MFileVersion = '1.16';
            VersionDate = '20240425';
            app.figure1.Name = ['AzfpQuickView: v', MFileVersion ' (Build ' VersionDate ')'];
            
            %
            % make Output dir
            warning off;
            try
                mkdir([fileparts(mfilename('fullpath')) '\output']);
                %set(app.StatusBar,'value',['Temp folder: ' fileparts(mfilename('fullpath')) '\output']);
            catch exception
                set(app.StatusBar,'value',sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message));
                return;
            end
            warning on;
            
            % setup tables
            app.SummaryTable.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.EchoTable.BackgroundColor = [1 1 1;0.9412 0.9412 0.9412];
            app.SummaryTable.ColumnFormat = {'char' 'short' 'short' 'short' 'short' 'short'};
            app.EchoTable.ColumnFormat = {'char' 'char' 'short' 'logical'};
            app.SummaryTable.RowName = {'START'; '30%'; '50%'; '70%'; 'END'};
            app.EchoTable.RowName = {'Segment #1'; 'Segment #2'; 'Segment #3'; 'Segment #4'; 'Segment #5'};
            app.hasTemperature = 1; % assume azfp has temperature readings, unless XML A/B/C = 0
            app.ULSFileExt = '*.0*.'; % .01A etc to search for uls5 data
            app.isULS6 = 0;
            warning off MATLAB:subscripting:noSubscriptsSpecified;
            
            set(app.SummaryTable,'data',cell(5,6));
            set(app.SummaryTable,'userdata',cell(5,6));
            set(app.EchoTable,'data',cell(5,4));
            set(app.StatusBar,'value','Step 1 - Select AZFP raw \DATA directory');
            
            % check if PDFLATEX is installed
            app.Lamp.Color = [0 1 0];
            status=system('pdflatex --version');
            if(status)
                app.Lamp.Color = [1 0 0];
                app.InstallMiKTeXButton.Enable = 1;
                app.InstallMiKTeXButton.Visible = 1;
                set(app.StatusBar,'value','Install MiKTeX using the button on the upper right');
            end
            
            warning off
            % check if prog settings were saved
            if(exist([fileparts(mfilename('fullpath')) '\AZFP_QuickView_Settings.mat'],'file')==2)
                load('AZFP_QuickView_Settings.mat','Par');
                try
                    cd(Par.RawFileTextLoc);
                catch
                end
                try
                    
                    app.PlotSensorDataCheckBox.Value = Par.PlotSensorCheckBox;
                    app.BatteryCheckBox_L1.Value = Par.BatteryCheckBox_L1;
                    app.BatteryCheckBox_R1.Value = Par.BatteryCheckBox_R1;
                    app.TempCheckBox_L1.Value = Par.TempCheckBox_L1;
                    app.TempCheckBox_R1.Value = Par.TempCheckBox_R1;
                    app.TiltCheckBox_L1.Value = Par.TiltCheckBox_L1;
                    app.TiltCheckBox_R1.Value = Par.TiltCheckBox_R1;
                    app.DepthCheckBox_L1.Value = Par.DepthCheckBox_L1;
                    app.DepthCheckBox_R1.Value = Par.DepthCheckBox_R1;
                    app.BatteryCheckBox_L2.Value = Par.BatteryCheckBox_L2;
                    app.BatteryCheckBox_R2.Value = Par.BatteryCheckBox_R2;
                    app.TempCheckBox_L2.Value = Par.TempCheckBox_L2;
                    app.TempCheckBox_R2.Value = Par.TempCheckBox_R2;
                    app.TiltCheckBox_L2.Value = Par.TiltCheckBox_L2;
                    app.TiltCheckBox_R2.Value = Par.TiltCheckBox_R2;
                    app.DepthCheckBox_L2.Value = Par.DepthCheckBox_L2;
                    app.DepthCheckBox_R2.Value = Par.DepthCheckBox_R2;
                    app.SensorYLim1_L1.Value = Par.SensorYLim1_L1;
                    app.SensorYLim2_L1.Value = Par.SensorYLim2_L1;
                    app.SensorYLim1_R1.Value = Par.SensorYLim1_R1;
                    app.SensorYLim2_R1.Value = Par.SensorYLim2_R1;
                    app.SensorYLim1_L2.Value = Par.SensorYLim1_L2;
                    app.SensorYLim2_L2.Value = Par.SensorYLim2_L2;
                    app.SensorYLim1_R2.Value = Par.SensorYLim1_R2;
                    app.SensorYLim2_R2.Value = Par.SensorYLim2_R2;
                    
                    app.DaystoPlotSpinner.Value = Par.DaystoPlotSpinner;
                    app.PlotAllDataCheckBox.Value =	Par.PlotAllDataCheckBox;
                    app.RangeBinstoAvgSpinner.Value = Par.RangeBinstoAvgSpinner;
                    app.PingstoAvgSpinner.Value = Par.PingstoAvgSpinner;
                    app.YLimitmSpinner.Value = Par.YLimitmSpinner;
                    app.AutoCheckBox.Value = Par.AutoCheckBox;
                    app.Value2Plot.Value = Par.Value2Plot;
                    Value2PlotValueChanged(app, '');
                    app.maxSpinner.Value = Par.maxSpinner;
                    app.minSpinner.Value = Par.minSpinner;
                    app.FigureresolutionDropDown.Value = Par.FigureresolutionDropDown;
                    app.PlotOffsetSpinner.Value = Par.PlotOffsetSpinner;
                    app.OrientationSwitch.Value = Par.OrientationSwitch;
                    app.TiltCorrectSwitch.Value = Par.TiltCorrectSwitch;
                    app.SalinitypsuSpinner.Value = Par.SalinitypsuSpinner;
                    app.WaterTempSpinner.Value = Par.WaterTempSpinner;
                    app.ApproxDepthmSpinner.Value = Par.ApproxDepthmSpinner;
                    app.Channel1Spinner.Value = Par.Channel1Spinner;
                    app.Channel2Spinner.Value = Par.Channel2Spinner;
                    app.Channel3Spinner.Value = Par.Channel3Spinner;
                    app.Channel4Spinner.Value = Par.Channel4Spinner;
                    %app.RawFileTextLoc.Text = Par.RawFileTextLoc;
                    
                    % update check boxes
                    AutoCheckBoxValueChanged(app, '');
                    
                catch exception
                    app.StatusBar.Value = sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message);
                end
            end
            warning on
        end

        % Button pushed function: Load, Load_2
        function Load_Callback(app, event)
            % hObject    handle to LoadData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % default all buttons
            DefaultButton(app);
            Stats = cell(5,6); % for table
            Stats2 = cell(5,6); % to save for pdf output, 5 foldersapp.SummaryTab
            Stats3 = cell(24,6); % for plot, all monthly folders
            set(app.RawFileTextLoc,'text','Select raw AZFP data directory, example c:\xyz\DATA');
            set(app.RawFileTextLoc2,'text','');
            Parameters.ProcDir = 0;
            yyaxis(app.axes1,'left');cla(app.axes1);
            yyaxis(app.axes1,'right');cla(app.axes1);
            set(app.axes1,'ycolor','m');
            cla(app.FileSizeAxes);
            Parameters.datafilename = '';
            Parameters.xmlfilename = '';
            Parameters.xmlpathname = '';
            Parameters.dplfilename = '';
            Parameters.dplpathname = '';
            Parameters.Salinity = 0;
            Parameters.Bins2Avg = 1;
            Parameters.Time2Avg = 1;
            Parameters.Pressure = 50;
            Parameters.Plot = 0;
            Parameters.Channel = 1;
            Parameters.Value2Plot = 1;
            Parameters.NoiseFloor = [0;0;0;0];
            Parameters.Orientation = 0;
            Parameters.UseTiltCorr = 0;
            % xml and dpl loaded?
            XMLLoaded = 0;
            DPLLoaded = 0;
            app.ULSFileExt = '*.0*.'; % .01A etc
            app.isULS6 = 0; % default to uls5 data 

            try
                
                % load a data file
                set(app.RawFileTextLoc,'text','Loading ...');
                drawnow;
                app.Load.Enable = 'off';
                app.Load_2.Enable = 'off';
                app.ULS6Phase.Enable = 0;
                
                % select the upper DATA directory
                % split into 5 sections: first sub-dir, 30%, 50% 70%, last
                % then load one data file in each folder and pull out data
                dirname = uigetdir('', 'Select TOP AZFP DATA directory');

                if(~dirname)
                    DefaultButton(app);
                    return;
                end
                cd(dirname);

                % parent directory should be 'DATA' 
                parent = strsplit(dirname, filesep);
                try
                    loc = find(contains(parent,'DATA'));
                    if(loc+1 == length(parent))
                        cd ..;
                    elseif(loc+2 == length(parent))
                        cd ..;cd ..;
                    end
                    if(isempty(loc))
                        try
                            cd 'DATA';
                        catch
                            % stay in selected folder
                        end
                    end
                    dirname = pwd;
                catch
                    set(app.StatusBar,'value','No files found...Select \DATA directory !!');
                    app.Load.Enable = 'on';
                    app.Load_2.Enable = 'on';
                    app.ULS6Phase.Enable = 1;
                    set(app.RawFileTextLoc,'text','');set(app.RawFileTextLoc2,'text','');
                    return;
                end
                                
                % get folders and first XML
                D = dir;
                num = 1;
                Folders = [];
                FilesPerDay = []; % for uls6 data to estimate expected num files
                for(ii=1:length(D))
                    if(D(ii).isdir && ~strcmp(D(ii).name,'.') && ~strcmp(D(ii).name,'..'))
                        % check for empty folders
                        if(length(dir([D(ii).name '/*.0*.'])) > 2)
                            Folders{num} = D(ii).name;
                            num = num + 1;
                        elseif(length(dir([D(ii).name '/*_0' app.ULS6Phase.Value 'A.azfp.'])) > 2) % uls6 data
                            app.ULSFileExt = ['*_0' app.ULS6Phase.Value 'A.azfp.'];
                            Parameters.QuickScan = 1;
                            app.isULS6 = 1;
                            Folders{num} = D(ii).name;
                            FilesPerDay(num) = length(dir([D(ii).name '/**/*_0' app.ULS6Phase.Value 'A.azfp']));
                            num = num + 1;
                        end
                        % get xml filename and path, check ALL folders
                        if(~isempty(dir([D(ii).name '/*.XML'])))
                            d = dir([D(ii).name '/*.XML']);
                            Parameters.xmlfilename = d(end).name;
                            Parameters.xmlpathname = d(end).folder;
                            XMLLoaded = 1;                              
                        end
                        % get dpl filename and path, check ALL folders
                        if(~isempty(dir([D(ii).name '/*.DPL'])))
                            d = dir([D(ii).name '/*.DPL']);
                            Parameters.dplfilename = d(end).name;
                            Parameters.dplpathname = d(end).folder;
                            DPLLoaded = 1;  
                        end
                    end
                end
                if(isempty(Folders))
                    set(app.StatusBar,'value','No files found...Select \DATA directory !!');
                    app.Load.Enable = 'on';
                    app.Load_2.Enable = 'on';
                    app.ULS6Phase.Enable = 1;
                    set(app.RawFileTextLoc,'text','');set(app.RawFileTextLoc2,'text','');
                    return;
                end
                % switch to SensorDataTab
                %app.TabGroup.SelectedTab = app.SensorSummaryTab;
                app.Lamp.Color = [1 0 0];
                
                % now divide this into 5
                loc = [1;ceil(length(Folders)*.3);ceil(length(Folders)*.5);ceil(length(Folders)*.7);length(Folders)];
%                 if(app.isULS6)
%                     Folders = Folders(loc); % since uls6 has daily folders
%                 end
                jj = 1;
                % keep tabs on # files actual, min/max/avg/tot file sizes
                FileInfo = [];
                FileStart = '';
                FileEnd = '';
                % load log files
                LogFileContents = {};
                % load deployment file
                DeploymentFileContents = {};
                
                numPass = 1;
                % do every folder for plots, only 5 for stats table
                for(ii=1:length(Folders))
                    cd(char(Folders(ii)));
                    % find and output deployment file info
                    d = dir('*.DPL');
                    if(~isempty(d) || DPLLoaded)
                        if(~isempty(d))
                            DeploymentFileContents = {fileread(d(1).name)};
                        else
                            DeploymentFileContents = {fileread(fullfile(Parameters.dplpathname,Parameters.dplfilename))};
                        end

                        % get acq start time from DPL file
                        acqloc = strfind(DeploymentFileContents,'Acquisition Start:');
                        acqloc = acqloc{:};
                        dpl = cell2mat(DeploymentFileContents);
                        if(length(acqloc)>=1)
                            app.AcquisitionStart = datenum(dpl(acqloc(end)+19:acqloc(end)++19+20));
                            app.StartDatefromdplfileTextArea.Value = datestr(app.AcquisitionStart,'mmm dd, yyyy HH:MM:SS');
                        end
                    end
                    
                    % skip folder if < Acq start date
                    if(app.AcquisitionStart - datenum([Folders{ii} '01'],'yyyymmdd') > 31)
                        cd(dirname);
                        continue;
                    end

                    d = dir(app.ULSFileExt);% uls5 or uls6 data
                    % ignore 0 byte files
                    d = d(cellfun(@any, {d.bytes}));
                    FileInfo = [FileInfo;cell2mat({d.bytes})'];
                    if(isempty(d))
                        cd(dirname);
                        continue;
                    end                   

                    if(ii<length(Folders)||length(Folders)==1)
                        Parameters.datafilename = d(1).name;
                        FileEnd = d(end).name; % save folder end date in case this is the only folder
                    else
                        try % maybe the last dir has only 1 file, or no files
                            % get last largest file size (last file may be
                            % 0 bytes)
                            [~,l] = max(fliplr([d.bytes]));
                            Parameters.datafilename = d(numel(d)-l+1).name;
                            FileEnd = Parameters.datafilename;
                        catch
                        end
                    end
                    if(~XMLLoaded)
                        d = dir('*.xml');
                        % if there are multiple xml files then load the last one
                        if(~isempty(d))
                            Parameters.xmlfilename = d(end).name;
                            Parameters.xmlpathname = d(end).folder;
                            XMLLoaded = 1;                                              
                        end                                               
                    end
                    if(numPass == 1)
                        % save first file name
                        FileStart = Parameters.datafilename;
                    end

                    [Output,Parameters] = ProcessAZFP(Parameters);
                    if(isempty(Output) && length(Folders) > 1)
                        cd(dirname);
                        continue;
                    end
                    % find and append log file info
                    d = dir('*.LOG');
                    for(kk=1:length(d))
                        LogFileContents(end+1,:) = {['[' datestr(d(kk).date,'dd-mmm-yyyy') '] ' fileread(d(kk).name)]};%{fileread(d(kk).name)};
                    end
                    
                    set(app.StatusBar,'value',['Summary => Step 1 - Loading file: ' Parameters.datafilename]);
                    drawnow;
                    cd(dirname);
                    if(~isempty(Output))
                        if(ismember(ii,loc))
                            Stats(jj,:) = {datestr(Output(1).Date(1),'mmm dd, yyyy HH:MM:SS') sprintf('%5.2f',mean(Output(1).BatteryMain)) sprintf('%.2f',mean(Output(1).BatteryTx)) sprintf('%.2f',mean(Output(1).Tx)) sprintf('%.2f',mean(Output(1).Ty)) sprintf('%.2f',mean(Output(1).T))};
                            Stats2(jj,:) = {Output(1).Date(1) mean(Output(1).BatteryMain) mean(Output(1).BatteryTx) mean(Output(1).Tx) mean(Output(1).Ty) mean(Output(1).T)};
                            Stats3(ii,:) = Stats2(jj,:);
                            jj = jj + 1;
                        else
                            Stats3(ii,:) = {Output(1).Date(1) mean(Output(1).BatteryMain) mean(Output(1).BatteryTx) mean(Output(1).Tx) mean(Output(1).Ty) mean(Output(1).T)};
                        end
                    end
                    numPass = numPass + 1;
                end
                
                % check if AZFP has temperature coeff
                if(~Parameters.A && ~Parameters.B && ~Parameters.C)
                    app.hasTemperature = 0;
                    % undim Temp input
                    app.WaterTempSpinner.Enable = 1;
                    app.WaterTempdegCSpinnerLabel.Enable = 1;
                end
                % calc and save File Start/End dates
                Parameters.FileStart = FileStart;
                Parameters.FileEnd = FileEnd;
                s = strtok(Parameters.FileStart,'.');
                Parameters.FileStartDate = datenum([s(3:4) '/' s(5:6) '/' s(1:2) ' ' s(7:8) ':00']);
                % check startdate compared to Acq start in DPL file. May be
                % old instrument setup folders that will mess up segment
                % calculations
                if(~isempty(app.AcquisitionStart))
                    if(app.AcquisitionStart - Parameters.FileStartDate > 0.5)
                        msgbox(['Data < DPL file start date are being ignored: ' Parameters.FileStart],'Warning');
                        app.StartDatefromdplfileTextArea.FontColor = 'r';
                        app.StartDatefromdplfileTextArea.BackgroundColor = 'y';
                        app.FileSummaryTable.BackgroundColor = [1 1 0;.94 .94 .94];
                        app.FileSummaryTable.FontWeight = 'bold';
                        app.FileSummaryTable.ForegroundColor = 'r';
                    end
                end
                s = strtok(Parameters.FileEnd,'.');
                Parameters.FileEndDate = datenum([s(3:4) '/' s(5:6) '/' s(1:2) ' ' s(7:8) ':00']);
                % save File start/end to app property
                app.FileStartDate = Parameters.FileStartDate;
                app.FileEndDate = Parameters.FileEndDate;
                
                if(app.FileEndDate-app.FileStartDate < app.DaystoPlotSpinner.Value)
                    app.DaystoPlotSpinner.Limits = [0.5 ceil(app.FileEndDate-app.FileStartDate)];
                    dtps = ceil((app.FileEndDate-app.FileStartDate)*10)/10;
                    if(dtps < 0.5) dtps = 0.5; end
                    app.DaystoPlotSpinner.Value = dtps;
                end
                
                % fill in file summary table
                NumFilesExpected = round((Parameters.FileEndDate-Parameters.FileStartDate)*24)+1;
                missing = NumFilesExpected-length(FileInfo);
                missing(missing<0) = 0;
                if(app.isULS6) % not hourly aymore so we don't know # expected or missing
                    NumFilesExpected = NumFilesExpected*ceil(mean(FilesPerDay))/24;
                    %missing = NaN;
                end
                Data = {datestr(Parameters.FileStartDate,'mmm dd, yyyy HH:MM:SS') datestr(Parameters.FileEndDate,'mmm dd, yyyy HH:MM:SS') NumFilesExpected length(FileInfo) missing num2str(min(FileInfo)/1e6,'%.2f') num2str(max(FileInfo)/1e6,'%.2f') num2str(mean(FileInfo)/1e6,'%.2f') num2str(sum(FileInfo)/1e9,'%.1f')};
                set(app.FileSummaryTable,'data',Data);
                app.RawFileTextLoc.UserData = Parameters;
                
                % trim
                Stats3(length(Folders)+1:end,:) = [];
                set(app.SummaryTable,'data',Stats);
                set(app.Load,'userdata',Stats2);
                set(app.SN,'text',num2str(Parameters.SerialNumber));
                
                % battery level/tilt plot
                app.SensorFigHandle = figure('visible','off');
                set(app.SensorFigHandle,'Position',[20 20 1200 800]);
                % plot sensor data to app window and to hidden figure
                PlotSensor(app,Folders,Stats3,0);
                set(0, 'CurrentFigure', app.SensorFigHandle);
                PlotSensor(app,Folders,Stats3,1);
                set(gca,'ycolor','m');
                print(app.SensorFigHandle,'-dpng','-r150',[fileparts(mfilename('fullpath')) '\output\summary.png']);
                close(app.SensorFigHandle);
                
                set(app.RawFileTextLoc,'text',pwd);set(app.RawFileTextLoc2,'text',pwd);set(app.RawFileTextLoc3,'text',pwd);
                
                % display log file contents
                if(~isempty(LogFileContents))
                    set(app.LogFileOutputTextArea,'Value', LogFileContents);
                else
                    set(app.LogFileOutputTextArea,'Value', 'Log file not found');
                end
                % display deployment file contents
                if(~isempty(DeploymentFileContents))
                    set(app.DeploymentFileTextArea,'Value', DeploymentFileContents);
                else
                    set(app.DeploymentFileTextArea,'Value', 'Deployment file not found');
                end
                
                % do filesize figure
                app.FileSizeFigHandle = figure('visible','off');
                set(app.FileSizeFigHandle,'Position',[20 20 1200 300]);
                % plot sensor data to app window and to hidden figure
                PlotFileSize(app,0);
                set(0, 'CurrentFigure', app.FileSizeFigHandle);  
                PlotFileSize(app,1);
                print(app.FileSizeFigHandle,'-dpng','-r150',[fileparts(mfilename('fullpath')) '\output\FSsummary.png']);
                close(app.FileSizeFigHandle);
                
                set(app.StatusBar,'value','Summary => Finished Step 1, Step 2 select PLOT in Echogram Tab');
                
                % enable run button in echogram
                app.PlotButton.Enable = 'on';
                app.Load.Enable = 'on';
                app.Load_2.Enable = 'on';
                app.DaystoPlotSpinner.Enable = 'on';
                app.DaystoPlotpersegmentSpinnerLabel.Enable = 'on';
                app.PlotAllDataCheckBox.Enable = 'on';
                app.PlotOffsetSpinner.Enable = 'on';
                app.PlotOffsetfromStartEndLabel.Enable = 'on';
                app.Lamp.Color = [0 1 0];
                % calc segments to plot ie. 5 week segments by default
                CalcEchoSegments(app);
                %load noise floor from xlsx
                LoadNF(app);
                app.ULS6Phase.Enable = 1;
                % switch to Echogram Tab
                %app.TabGroup.SelectedTab = app.EchogramsTab;
            catch exception
                DefaultButton(app);
                set(app.StatusBar,'value',sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message));
                cd(dirname);                 
                return;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CancelButton.UserData = 1;
            app.CancelButton.Enable = 0;
            app.Lamp.Color = [0 1 0];
            app.DaystoPlotSpinner.Enable = 'on';
            app.DaystoPlotpersegmentSpinnerLabel.Enable = 'on';
            app.PlotAllDataCheckBox.Enable = 'on';
            app.PlotOffsetSpinner.Enable = 'on';
            app.PlotOffsetfromStartEndLabel.Enable = 'on';
            app.Load.Enable = 'on';
            app.Load_2.Enable = 'on';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.CancelButton.UserData = 0;
            app.CancelButton.Enable = 1;
            % fill in the weekly echogram table using the data
            if(~isempty(app.RawFileTextLoc.UserData))
                tic;
                try
                    app.Lamp.Color = [1 0 0];
                    app.Lamp_1.Color = [0.8 0.8 0.8];
                    app.Lamp_2.Color = [0.8 0.8 0.8];
                    app.Lamp_3.Color = [0.8 0.8 0.8];
                    app.Lamp_4.Color = [0.8 0.8 0.8];
                    app.Lamp_5.Color = [0.8 0.8 0.8];
                    app.PlotButton.Enable = 'off';
                    app.Load.Enable = 'off';
                    app.Load_2.Enable = 'off';
                    app.DaystoPlotSpinner.Enable = 'off';
                    app.DaystoPlotpersegmentSpinnerLabel.Enable = 'off';
                    app.PlotAllDataCheckBox.Enable = 'off';
                    app.PlotOffsetSpinner.Enable = 'off';
                    app.PlotOffsetfromStartEndLabel.Enable = 'off';
                    app.Value2Plot.Enable = 'off';
                    app.ULS6Phase.Enable = 0;
                    DoEchograms(app);
                    app.PlotButton.Enable = 'on';
                    app.Load.Enable = 'on';
                    app.Load_2.Enable = 'on';
                    app.DaystoPlotSpinner.Enable = 'on';
                    app.DaystoPlotpersegmentSpinnerLabel.Enable = 'on';
                    app.PlotAllDataCheckBox.Enable = 'on';
                    app.PlotOffsetSpinner.Enable = 'on';
                    app.PlotOffsetfromStartEndLabel.Enable = 'on';
                    app.Value2Plot.Enable = 'on';
                    app.Lamp.Color = [0 1 0];
                    app.ULS6Phase.Enable = 1;
                catch exception
                    set(app.StatusBar,'value',sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message));
                    app.Lamp.Color = [0 1 0];
                    app.PlotButton.Enable = 'on';
                    app.Load.Enable = 'on';
                    app.Load_2.Enable = 'on';
                    app.DaystoPlotSpinner.Enable = 'on';
                    app.DaystoPlotpersegmentSpinnerLabel.Enable = 'on';
                    app.PlotAllDataCheckBox.Enable = 'on';
                    app.PlotOffsetSpinner.Enable = 'on';
                    app.PlotOffsetfromStartEndLabel.Enable = 'on';
                    app.Value2Plot.Enable = 'on';
                    app.ULS6Phase.Enable = 1;
                    return;
                end
                fprintf('Elapsed Time = %.1f minutes\n',toc/60);
            end
            
            
        end

        % Value changed function: DaystoPlotSpinner, PlotOffsetSpinner
        function DaystoPlotSpinnerValueChanged(app, event)
            
            app.Lamp_1.Color = [0.8 0.8 0.8];
            app.Lamp_2.Color = [0.8 0.8 0.8];
            app.Lamp_3.Color = [0.8 0.8 0.8];
            app.Lamp_4.Color = [0.8 0.8 0.8];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            try
                CalcEchoSegments(app);
            catch exception
                set(app.StatusBar,'value',sprintf('Error: %s() line %d: "%s"',exception.stack(1).name, exception.stack(1).line, exception.message));
                app.Lamp.Color = [0 1 0];
                app.PlotButton.Enable = 'on';
                app.DaystoPlotSpinner.Enable = 'on';
                app.DaystoPlotpersegmentSpinnerLabel.Enable = 'on';
                app.PlotAllDataCheckBox.Enable = 'on';
                app.PlotOffsetSpinner.Enable = 'on';
                app.PlotOffsetfromStartEndLabel.Enable = 'on';
                app.FileStartDate = [];
                app.FileEndDate = [];
                app.DaystoPlotSpinner.Limits = [0.5 30];
                return;
            end
        end

        % Cell edit callback: EchoTable
        function EchoTableCellEdit(app, event)
            if(~isempty(app.EchoTable.Data{1}))% no data
                if(app.PlotAllDataCheckBox.Value)
                    app.EchoTable.Data{1,3} = ceil((datenum(app.EchoTable.Data{1,2})-datenum(app.EchoTable.Data{1,1}))/app.DaystoPlotSpinner.Value);
                else
                    app.EchoTable.Data{event.Indices(1),3} = double(app.EchoTable.Data{event.Indices(1),4});
                end
            end
            
        end

        % Button pushed function: InstallMiKTeXButton
        function InstallMiKTeXButtonPushed(app, event)
            url = 'https://miktex.org/download';
            web(url);
        end

        % Value changed function: PlotAllDataCheckBox
        function PlotAllDataCheckBoxValueChanged(app, event)
            app.Lamp_1.Color = [0.8 0.8 0.8];
            app.Lamp_2.Color = [0.8 0.8 0.8];
            app.Lamp_3.Color = [0.8 0.8 0.8];
            app.Lamp_4.Color = [0.8 0.8 0.8];
            app.Lamp_5.Color = [0.8 0.8 0.8];
            value = app.PlotAllDataCheckBox.Value;
            t = {'# Days to Plot'; 'per segment'};
            if(value)
                t{2} = 'per page';
            end
            app.DaystoPlotpersegmentSpinnerLabel.Text = t;
            CalcEchoSegments(app);
        end

        % Value changed function: Value2Plot
        function Value2PlotValueChanged(app, event)
            
            % change clim
            if(app.Value2Plot.Value == 1)
                SetSpinnerLimits(app,0,65500,500,[0 65500],'Colourbar (counts)',0);
            else
                SetSpinnerLimits(app,-120,-40,1,[-150 50],'Colourbar (dB)',1);
            end
            
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            
            app.YLimitmSpinner.Enable = ~app.AutoCheckBox.Value;
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
            % save settings
            Par.PlotSensorCheckBox = app.PlotSensorDataCheckBox.Value;
            Par.BatteryCheckBox_L1 = app.BatteryCheckBox_L1.Value;
            Par.BatteryCheckBox_R1 = app.BatteryCheckBox_R1.Value;
            Par.TempCheckBox_L1 = app.TempCheckBox_L1.Value;
            Par.TempCheckBox_R1 = app.TempCheckBox_R1.Value;
            Par.TiltCheckBox_L1 = app.TiltCheckBox_L1.Value;
            Par.TiltCheckBox_R1 = app.TiltCheckBox_R1.Value;
            Par.DepthCheckBox_L1 = app.DepthCheckBox_L1.Value;
            Par.DepthCheckBox_R1 = app.DepthCheckBox_R1.Value;
            Par.BatteryCheckBox_L2 = app.BatteryCheckBox_L2.Value;
            Par.BatteryCheckBox_R2 = app.BatteryCheckBox_R2.Value;
            Par.TempCheckBox_L2 = app.TempCheckBox_L2.Value;
            Par.TempCheckBox_R2 = app.TempCheckBox_R2.Value;
            Par.TiltCheckBox_L2 = app.TiltCheckBox_L2.Value;
            Par.TiltCheckBox_R2 = app.TiltCheckBox_R2.Value;
            Par.DepthCheckBox_L2 = app.DepthCheckBox_L2.Value;
            Par.DepthCheckBox_R2 = app.DepthCheckBox_R2.Value;
            Par.SensorYLim1_L1 = app.SensorYLim1_L1.Value;
            Par.SensorYLim2_L1 = app.SensorYLim2_L1.Value;
            Par.SensorYLim1_R1 = app.SensorYLim1_R1.Value;
            Par.SensorYLim2_R1 = app.SensorYLim2_R1.Value;
            Par.SensorYLim1_L2 = app.SensorYLim1_L2.Value;
            Par.SensorYLim2_L2 = app.SensorYLim2_L2.Value;
            Par.SensorYLim1_R2 = app.SensorYLim1_R2.Value;
            Par.SensorYLim2_R2 = app.SensorYLim2_R2.Value;
            
            Par.DaystoPlotSpinner = app.DaystoPlotSpinner.Value;
            Par.PlotAllDataCheckBox = app.PlotAllDataCheckBox.Value;
            Par.RangeBinstoAvgSpinner = app.RangeBinstoAvgSpinner.Value;
            Par.PingstoAvgSpinner = app.PingstoAvgSpinner.Value;
            Par.YLimitmSpinner = app.YLimitmSpinner.Value;
            Par.AutoCheckBox = app.AutoCheckBox.Value;
            Par.maxSpinner = app.maxSpinner.Value;
            Par.minSpinner = app.minSpinner.Value;
            Par.FigureresolutionDropDown = app.FigureresolutionDropDown.Value;
            Par.PlotOffsetSpinner = app.PlotOffsetSpinner.Value;
            Par.OrientationSwitch = app.OrientationSwitch.Value;
            Par.TiltCorrectSwitch = app.TiltCorrectSwitch.Value;
            Par.SalinitypsuSpinner = app.SalinitypsuSpinner.Value;
            Par.WaterTempSpinner = app.WaterTempSpinner.Value;
            Par.ApproxDepthmSpinner = app.ApproxDepthmSpinner.Value;
            Par.Channel1Spinner = app.Channel1Spinner.Value;
            Par.Channel2Spinner = app.Channel2Spinner.Value;
            Par.Channel3Spinner = app.Channel3Spinner.Value;
            Par.Channel4Spinner = app.Channel4Spinner.Value;
            %Par.RawFileTextLoc = app.RawFileTextLoc.Text;
            Par.Value2Plot = app.Value2Plot.Value;
            
            save([fileparts(mfilename('fullpath')) '\AZFP_QuickView_Settings.mat'],'Par');
            
            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            winopen(pwd);
        end

        % Callback function: HelpButton, OpenCHMhelpMenu
        function HelpButtonPushed(app, event)
            thePath = ShowPath(app);
            f = [thePath '\AzfpQuickView_UserManual.chm'];
            app.StatusBar.Value = ['Opening help file: ' f];
            if(exist(f,'file')==2)
                winopen(f);
            else
                winopen('AzfpQuickView_UserManual.chm');
            end
        end

        % Menu selected function: OpenPDFhelpMenu
        function OpenPDFhelpMenuSelected(app, event)
            thePath = ShowPath(app);
            f = [thePath '\AzfpQuickView_UserManual.pdf'];
            app.StatusBar.Value = ['Opening help file: ' f];
            if(exist(f,'file')==2)
                winopen(f);
            else
                winopen('AzfpQuickView_UserManual.pdf');
            end
        end

        % Value changed function: ULS6Phase
        function ULS6PhaseValueChanged(app, event)
            value = app.ULS6Phase.Value;
            DefaultButton(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'azfpcalib';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.StatusBar = uitextarea(app.figure1);
            app.StatusBar.Editable = 'off';
            app.TabGroup = uitabgroup(app.figure1);
            app.FileSummaryTab = uitab(app.TabGroup);
            app.FileSummaryTab.Title = 'File Summary';
            app.FileSizeAxes = uiaxes(app.FileSummaryTab);
            ylabel(app.FileSizeAxes, 'File size (MB)')
            app.Load = uibutton(app.FileSummaryTab, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @Load_Callback, true);
            app.Load.Tooltip = {'Select AZFP \DATA directory'};
            app.Load.Text = 'Select DATA Directory';
            app.RawFileTextLoc = uilabel(app.FileSummaryTab);
            app.RawFileTextLoc.Text = ' Select raw AZFP data directory, example c:\xyz\DATA';
            app.FileSummaryTable = uitable(app.FileSummaryTab);
            app.FileSummaryTable.ColumnName = {'Start Date'; 'End Date'; 'Expected'; 'Actual'; 'Missing'; 'Min (kB)'; 'Max (kB)'; 'Avg (kB)'; 'Tot (GB)'};
            app.FileSummaryTable.RowName = {};
            app.FileSummaryLabel = uilabel(app.FileSummaryTab);
            app.FileSummaryLabel.Text = 'File Summary';
            app.text17 = uilabel(app.FileSummaryTab);
            app.text17.Text = 'S/N:';
            app.SN = uilabel(app.FileSummaryTab);
            app.SN.Text = '';
            app.TextArea = uitextarea(app.FileSummaryTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Number of Files'};
            app.TextArea_2 = uitextarea(app.FileSummaryTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'File Size'};
            app.LogFileOutputTextAreaLabel = uilabel(app.FileSummaryTab);
            app.LogFileOutputTextAreaLabel.Text = 'Log File Output';
            app.LogFileOutputTextArea = uitextarea(app.FileSummaryTab);
            app.DeploymentFileTextAreaLabel = uilabel(app.FileSummaryTab);
            app.DeploymentFileTextAreaLabel.Text = 'Deployment File';
            app.DeploymentFileTextArea = uitextarea(app.FileSummaryTab);
            app.InstallMiKTeXButton = uibutton(app.FileSummaryTab, 'push');
            app.InstallMiKTeXButton.ButtonPushedFcn = createCallbackFcn(app, @InstallMiKTeXButtonPushed, true);
            app.InstallMiKTeXButton.Enable = 'off';
            app.InstallMiKTeXButton.Visible = 'off';
            app.InstallMiKTeXButton.Tooltip = {'Install MiKTeX to generate PDF LaTeX style reports'};
            app.InstallMiKTeXButton.Text = 'Install MiKTeX';
            app.StartDatefromdplfileTextAreaLabel = uilabel(app.FileSummaryTab);
            app.StartDatefromdplfileTextAreaLabel.Text = 'Start Date (from *.dpl file)';
            app.StartDatefromdplfileTextArea = uitextarea(app.FileSummaryTab);
            app.StartDatefromdplfileTextArea.Editable = 'off';
            app.StartDatefromdplfileTextArea.Tooltip = {'Acquisition start date and time loaded from .DPL file.'};
            app.SensorSummaryTab = uitab(app.TabGroup);
            app.SensorSummaryTab.Title = 'Sensor Summary';
            app.axes1 = uiaxes(app.SensorSummaryTab);
            ylabel(app.axes1, 'Battery (V)')
            app.SummaryTable = uitable(app.SensorSummaryTab);
            app.SummaryTable.ColumnName = {'Date'; 'BatteryMain (V)'; 'BatteryTx (V)'; 'Tilt-X (deg)'; 'Tilt-Y (deg)'; 'Temp (C)'};
            app.SummaryTable.ColumnEditable = [false false false false false false];
            app.MonthlySensorDataSummaryLabel = uilabel(app.SensorSummaryTab);
            app.MonthlySensorDataSummaryLabel.Text = 'Monthly Sensor Data Summary';
            app.RawFileTextLoc3 = uilabel(app.SensorSummaryTab);
            app.RawFileTextLoc3.Text = ' Raw AZFP data directory (from Summary tab)';
            app.EchogramsTab = uitab(app.TabGroup);
            app.EchogramsTab.Title = 'Echograms';
            app.EchoTable = uitable(app.EchogramsTab);
            app.EchoTable.ColumnName = {'Start Date'; 'End Date'; '# Pages'; 'Plot'};
            app.EchoTable.ColumnEditable = [false false false true];
            app.EchoTable.CellEditCallback = createCallbackFcn(app, @EchoTableCellEdit, true);
            app.EchogramPlotSummaryLabel = uilabel(app.EchogramsTab);
            app.EchogramPlotSummaryLabel.Text = 'Echogram Plot Summary';
            app.RawFileTextLoc2 = uilabel(app.EchogramsTab);
            app.RawFileTextLoc2.Text = ' Raw AZFP data directory (from Summary tab)';
            app.CancelButton = uibutton(app.EchogramsTab, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'cancel.png');
            app.CancelButton.Text = 'Cancel';
            app.PlotButton = uibutton(app.EchogramsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Icon = fullfile(pathToMLAPP, 'plot.png');
            app.PlotButton.Enable = 'off';
            app.PlotButton.Tooltip = {'Make PDF plots'};
            app.PlotButton.Text = '  Plot';
            app.EchogramandProfilePlotSettingsPanel = uipanel(app.EchogramsTab);
            app.EchogramandProfilePlotSettingsPanel.Title = 'Echogram and Profile Plot Settings';
            app.YLimitmSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.YLimitmSpinnerLabel.Text = 'YLimit (m)';
            app.YLimitmSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.YLimitmSpinner.Step = 0.5;
            app.YLimitmSpinner.Limits = [1 1000];
            app.YLimitmSpinner.ValueDisplayFormat = '%.1f';
            app.YLimitmSpinner.Enable = 'off';
            app.YLimitmSpinner.Tooltip = {'Range YLimit for Echogram plots'};
            app.YLimitmSpinner.Value = 100;
            app.DaystoPlotpersegmentSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.DaystoPlotpersegmentSpinnerLabel.Enable = 'off';
            app.DaystoPlotpersegmentSpinnerLabel.Text = {'# Days to Plot'; 'per segment'};
            app.DaystoPlotSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.DaystoPlotSpinner.Step = 0.5;
            app.DaystoPlotSpinner.Limits = [0.5 30];
            app.DaystoPlotSpinner.ValueDisplayFormat = '%.1f';
            app.DaystoPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @DaystoPlotSpinnerValueChanged, true);
            app.DaystoPlotSpinner.Enable = 'off';
            app.DaystoPlotSpinner.Value = 3;
            app.RangeBinstoAvgSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.RangeBinstoAvgSpinnerLabel.Text = '# Range Bins to Avg';
            app.RangeBinstoAvgSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.RangeBinstoAvgSpinner.Limits = [1 100];
            app.RangeBinstoAvgSpinner.ValueDisplayFormat = '%.0f';
            app.RangeBinstoAvgSpinner.Tooltip = {'Number of range values to average'};
            app.RangeBinstoAvgSpinner.Value = 10;
            app.PingstoAvgSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.PingstoAvgSpinnerLabel.Text = '# Pings to Avg';
            app.PingstoAvgSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.PingstoAvgSpinner.Limits = [1 300];
            app.PingstoAvgSpinner.ValueDisplayFormat = '%.0f';
            app.PingstoAvgSpinner.Tooltip = {'Number of pings to average'};
            app.PingstoAvgSpinner.Value = 60;
            app.minSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.minSpinnerLabel.Text = 'min';
            app.minSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.minSpinner.Limits = [-150 50];
            app.minSpinner.ValueDisplayFormat = '%.0f';
            app.minSpinner.Value = -120;
            app.maxSpinnerLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.maxSpinnerLabel.Text = 'max';
            app.maxSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.maxSpinner.Limits = [-150 50];
            app.maxSpinner.ValueDisplayFormat = '%.0f';
            app.maxSpinner.Value = -40;
            app.ColourbardBLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.ColourbardBLabel.Text = 'Colourbar (dB)';
            app.FigureResolutionLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.FigureResolutionLabel.Text = 'Figure Resolution';
            app.FigureresolutionDropDown = uidropdown(app.EchogramandProfilePlotSettingsPanel);
            app.FigureresolutionDropDown.Items = {'0 screen res (1 MB)', '90 dpi (1 MB)', '120 dpi (3 MB)', '150 dpi (5 MB)', '200 dpi (7 MB)', '300 dpi (9 MB)'};
            app.FigureresolutionDropDown.Value = '150 dpi (5 MB)';
            app.PDFoutputsizeLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.PDFoutputsizeLabel.Text = '(PDF output size)';
            app.PlotOffsetfromStartEndLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.PlotOffsetfromStartEndLabel.Enable = 'off';
            app.PlotOffsetfromStartEndLabel.Text = {'Plot Offset from'; 'Start & End'};
            app.PlotOffsetSpinner = uispinner(app.EchogramandProfilePlotSettingsPanel);
            app.PlotOffsetSpinner.Step = 0.5;
            app.PlotOffsetSpinner.Limits = [0 240];
            app.PlotOffsetSpinner.ValueDisplayFormat = '%.1f';
            app.PlotOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @DaystoPlotSpinnerValueChanged, true);
            app.PlotOffsetSpinner.Enable = 'off';
            app.PlotOffsetSpinner.Value = 12;
            app.PlotAllDataCheckBox = uicheckbox(app.EchogramandProfilePlotSettingsPanel);
            app.PlotAllDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotAllDataCheckBoxValueChanged, true);
            app.PlotAllDataCheckBox.Enable = 'off';
            app.PlotAllDataCheckBox.Tooltip = {'Plot all data using # days to plot value for each page'};
            app.PlotAllDataCheckBox.Text = {'Plot All Data'; ''};
            app.hoursLabel = uilabel(app.EchogramandProfilePlotSettingsPanel);
            app.hoursLabel.Text = 'hours';
            app.Value2Plot = uidropdown(app.EchogramandProfilePlotSettingsPanel);
            app.Value2Plot.Items = {'Sv', 'Counts'};
            app.Value2Plot.ItemsData = [2 1];
            app.Value2Plot.ValueChangedFcn = createCallbackFcn(app, @Value2PlotValueChanged, true);
            app.Value2Plot.Tooltip = {'Plot Sv (dB) or raw Counts'};
            app.Value2Plot.Value = 2;
            app.AutoCheckBox = uicheckbox(app.EchogramandProfilePlotSettingsPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Tooltip = {'Auto calculate figure Y Limit (Range in meters)'};
            app.AutoCheckBox.Text = 'Auto';
            app.AutoCheckBox.Value = true;
            app.InstrumentSettingsPanel = uipanel(app.EchogramsTab);
            app.InstrumentSettingsPanel.Title = 'Instrument Settings';
            app.NoiseFloorcountsLabel = uilabel(app.InstrumentSettingsPanel);
            app.NoiseFloorcountsLabel.Text = 'Noise Floor (counts)';
            app.OrientationSwitchLabel = uilabel(app.InstrumentSettingsPanel);
            app.OrientationSwitchLabel.Text = 'Orientation';
            app.OrientationSwitch = uiswitch(app.InstrumentSettingsPanel, 'slider');
            app.OrientationSwitch.Items = {'DOWN', 'UP'};
            app.OrientationSwitch.Tooltip = {'Instrument orientation (up/down), reverses the Echogram plots'};
            app.OrientationSwitch.Value = 'UP';
            app.TiltCorrectSwitchLabel = uilabel(app.InstrumentSettingsPanel);
            app.TiltCorrectSwitchLabel.Text = 'Tilt Correct';
            app.TiltCorrectSwitch = uiswitch(app.InstrumentSettingsPanel, 'slider');
            app.TiltCorrectSwitch.Tooltip = {'Tilt correct the range values'};
            app.SalinitypsuSpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.SalinitypsuSpinnerLabel.Text = 'Salinity (psu)';
            app.SalinitypsuSpinner = uispinner(app.InstrumentSettingsPanel);
            app.SalinitypsuSpinner.Step = 0.1;
            app.SalinitypsuSpinner.Limits = [0 40];
            app.SalinitypsuSpinner.ValueDisplayFormat = '%.1f';
            app.SalinitypsuSpinner.Tooltip = {'Enter approximate Salinity (psu) for the sound speed calculation'};
            app.SalinitypsuSpinner.Value = 30;
            app.ApproxDepthmSpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.ApproxDepthmSpinnerLabel.Text = 'Approx Depth (m)';
            app.ApproxDepthmSpinner = uispinner(app.InstrumentSettingsPanel);
            app.ApproxDepthmSpinner.Limits = [0 1000];
            app.ApproxDepthmSpinner.ValueDisplayFormat = '%.0f';
            app.ApproxDepthmSpinner.Tooltip = {'Enter approximate instrument depth (used for sound speed calculation)'};
            app.ApproxDepthmSpinner.Value = 50;
            app.Channel1SpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.Channel1SpinnerLabel.Text = 'Channel 1';
            app.Channel1Spinner = uispinner(app.InstrumentSettingsPanel);
            app.Channel1Spinner.Limits = [0 20000];
            app.Channel1Spinner.ValueDisplayFormat = '%.0f';
            app.Channel1Spinner.Tooltip = {'Sv plots: values below the noise floor (counts) are not plotted'};
            app.Channel2SpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.Channel2SpinnerLabel.Text = 'Channel 2';
            app.Channel2Spinner = uispinner(app.InstrumentSettingsPanel);
            app.Channel2Spinner.Limits = [0 20000];
            app.Channel2Spinner.ValueDisplayFormat = '%.0f';
            app.Channel2Spinner.Tooltip = {'Sv plots: values below the noise floor (counts) are not plotted'};
            app.Channel3SpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.Channel3SpinnerLabel.Text = 'Channel 3';
            app.Channel3Spinner = uispinner(app.InstrumentSettingsPanel);
            app.Channel3Spinner.Limits = [0 20000];
            app.Channel3Spinner.ValueDisplayFormat = '%.0f';
            app.Channel3Spinner.Tooltip = {'Sv plots: values below the noise floor (counts) are not plotted'};
            app.Channel4SpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.Channel4SpinnerLabel.Text = 'Channel 4';
            app.Channel4Spinner = uispinner(app.InstrumentSettingsPanel);
            app.Channel4Spinner.Limits = [0 20000];
            app.Channel4Spinner.ValueDisplayFormat = '%.0f';
            app.Channel4Spinner.Tooltip = {'Sv plots: values below the noise floor (counts) are not plotted'};
            app.WaterTempdegCSpinnerLabel = uilabel(app.InstrumentSettingsPanel);
            app.WaterTempdegCSpinnerLabel.Enable = 'off';
            app.WaterTempdegCSpinnerLabel.Tooltip = {'Approx water temperature (only used if there is no internal Azfp temp)'};
            app.WaterTempdegCSpinnerLabel.Text = 'Water Temp (degC)';
            app.WaterTempSpinner = uispinner(app.InstrumentSettingsPanel);
            app.WaterTempSpinner.Step = 0.5;
            app.WaterTempSpinner.Limits = [-5 25];
            app.WaterTempSpinner.ValueDisplayFormat = '%.1f';
            app.WaterTempSpinner.Enable = 'off';
            app.WaterTempSpinner.Tooltip = {'Enter approximate Water Temperature (degC) for the sound speed calculation. This will be dimmed if there is an internal temp sensor.'};
            app.WaterTempSpinner.Value = 10;
            app.UsePsensor = uicheckbox(app.InstrumentSettingsPanel);
            app.UsePsensor.Tooltip = {'Use the pressure sensor for a Range offset (if Pressure is available)'};
            app.UsePsensor.Text = {'Use P sensor for '; 'Range offset'};
            app.Lamp_1 = uilamp(app.EchogramsTab);
            app.Lamp_2 = uilamp(app.EchogramsTab);
            app.Lamp_3 = uilamp(app.EchogramsTab);
            app.Lamp_4 = uilamp(app.EchogramsTab);
            app.Lamp_5 = uilamp(app.EchogramsTab);
            app.Load_2 = uibutton(app.EchogramsTab, 'push');
            app.Load_2.ButtonPushedFcn = createCallbackFcn(app, @Load_Callback, true);
            app.Load_2.Icon = fullfile(pathToMLAPP, 'openfile.png');
            app.Load_2.Tooltip = {'Select AZFP \DATA directory'};
            app.Load_2.Text = ' Load';
            app.Button = uibutton(app.EchogramsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'browse-folder.png');
            app.Button.Tooltip = {'Browse selected data directory'};
            app.Button.Text = '';
            app.ULS6DropDownLabel = uilabel(app.EchogramsTab);
            app.ULS6DropDownLabel.Text = 'ULS6';
            app.ULS6Phase = uidropdown(app.EchogramsTab);
            app.ULS6Phase.Items = {'Phase 1', 'Phase 2', 'Phase 3', 'Phase 4'};
            app.ULS6Phase.ItemsData = {'1', '2', '3', '4'};
            app.ULS6Phase.ValueChangedFcn = createCallbackFcn(app, @ULS6PhaseValueChanged, true);
            app.ULS6Phase.Tooltip = {'Select Phase to plot for ULS6 data. Do this BEFORE using the LOAD button.'};
            app.ULS6Phase.Value = '2';
            app.SensorTab = uitab(app.TabGroup);
            app.SensorTab.Title = 'Sensor';
            app.SummaryOutputSettingsPanel = uipanel(app.SensorTab);
            app.SummaryOutputSettingsPanel.Title = 'Summary Output Settings';
            app.PlotSensorDataCheckBox = uicheckbox(app.SummaryOutputSettingsPanel);
            app.PlotSensorDataCheckBox.Tooltip = {'Plot sensor data above Echogram plot'};
            app.PlotSensorDataCheckBox.Text = 'Plot Sensor Data';
            app.BatteryCheckBox_L1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.BatteryCheckBox_L1.Text = '';
            app.BatteryCheckBox_L1.Value = true;
            app.TempCheckBox_L1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TempCheckBox_L1.Text = '';
            app.TempCheckBox_L1.Value = true;
            app.TiltCheckBox_L1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TiltCheckBox_L1.Text = '';
            app.DepthCheckBox_L1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.DepthCheckBox_L1.Text = '';
            app.BatteryCheckBox_R1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.BatteryCheckBox_R1.Text = '';
            app.TempCheckBox_R1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TempCheckBox_R1.Text = '';
            app.TiltCheckBox_R1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TiltCheckBox_R1.Text = '';
            app.TiltCheckBox_R1.Value = true;
            app.DepthCheckBox_R1 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.DepthCheckBox_R1.Text = '';
            app.DepthCheckBox_R1.Value = true;
            app.Plot1Label = uilabel(app.SummaryOutputSettingsPanel);
            app.Plot1Label.Text = 'Plot 1';
            app.BatteryLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.BatteryLabel.Text = 'Battery';
            app.TempLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.TempLabel.Text = 'Temp';
            app.TiltLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.TiltLabel.Text = 'Tilt';
            app.DepthLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.DepthLabel.Text = 'Depth';
            app.YLim1Label = uilabel(app.SummaryOutputSettingsPanel);
            app.YLim1Label.Text = 'YLim1';
            app.SensorYLim1_L1 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim1_L1.Limits = [-1000 1000];
            app.SensorYLim1_L1.ValueDisplayFormat = '%.0f';
            app.SensorYLim1_L1.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim1_L1.Value = 6;
            app.YLim2Label = uilabel(app.SummaryOutputSettingsPanel);
            app.YLim2Label.Text = 'YLim2';
            app.SensorYLim2_L1 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim2_L1.Limits = [-1000 1000];
            app.SensorYLim2_L1.ValueDisplayFormat = '%.0f';
            app.SensorYLim2_L1.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim2_L1.Value = 16;
            app.SensorYLim1_R1 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim1_R1.Limits = [-1000 1000];
            app.SensorYLim1_R1.ValueDisplayFormat = '%.0f';
            app.SensorYLim1_R1.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim1_R1.Value = -20;
            app.SensorYLim2_R1 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim2_R1.Limits = [-1000 1000];
            app.SensorYLim2_R1.ValueDisplayFormat = '%.0f';
            app.SensorYLim2_R1.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim2_R1.Value = 20;
            app.LeftAxisLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.LeftAxisLabel.Text = 'Left Axis';
            app.RightAxisLabel = uilabel(app.SummaryOutputSettingsPanel);
            app.RightAxisLabel.Text = 'Right Axis';
            app.BatteryCheckBox_L2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.BatteryCheckBox_L2.Text = '';
            app.TempCheckBox_L2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TempCheckBox_L2.Text = '';
            app.TiltCheckBox_L2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TiltCheckBox_L2.Text = '';
            app.DepthCheckBox_L2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.DepthCheckBox_L2.Text = '';
            app.BatteryCheckBox_R2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.BatteryCheckBox_R2.Text = '';
            app.TempCheckBox_R2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TempCheckBox_R2.Text = '';
            app.TiltCheckBox_R2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.TiltCheckBox_R2.Text = '';
            app.DepthCheckBox_R2 = uicheckbox(app.SummaryOutputSettingsPanel);
            app.DepthCheckBox_R2.Text = '';
            app.Plot2Label = uilabel(app.SummaryOutputSettingsPanel);
            app.Plot2Label.Text = 'Plot 2';
            app.BatteryLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.BatteryLabel_2.Text = 'Battery';
            app.TempLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.TempLabel_2.Text = 'Temp';
            app.TiltLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.TiltLabel_2.Text = 'Tilt';
            app.DepthLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.DepthLabel_2.Text = 'Depth';
            app.SensorYLim1_R2 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim1_R2.Limits = [-1000 1000];
            app.SensorYLim1_R2.ValueDisplayFormat = '%.0f';
            app.SensorYLim1_R2.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim1_R2.Value = -20;
            app.SensorYLim2_R2 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim2_R2.Limits = [-1000 1000];
            app.SensorYLim2_R2.ValueDisplayFormat = '%.0f';
            app.SensorYLim2_R2.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim2_R2.Value = 20;
            app.LeftAxisLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.LeftAxisLabel_2.Text = 'Left Axis';
            app.RightAxisLabel_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.RightAxisLabel_2.Text = 'Right Axis';
            app.YLim1Label_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.YLim1Label_2.Text = 'YLim1';
            app.SensorYLim1_L2 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim1_L2.Limits = [-1000 1000];
            app.SensorYLim1_L2.ValueDisplayFormat = '%.0f';
            app.SensorYLim1_L2.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim1_L2.Value = 6;
            app.YLim2Label_2 = uilabel(app.SummaryOutputSettingsPanel);
            app.YLim2Label_2.Text = 'YLim2';
            app.SensorYLim2_L2 = uispinner(app.SummaryOutputSettingsPanel);
            app.SensorYLim2_L2.Limits = [-1000 1000];
            app.SensorYLim2_L2.ValueDisplayFormat = '%.0f';
            app.SensorYLim2_L2.Tooltip = {'Range YLimit for Echogram plots'};
            app.SensorYLim2_L2.Value = 16;
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.Lamp = uilamp(app.figure1);
            app.HelpButton = uibutton(app.figure1, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Icon = fullfile(pathToMLAPP, 'help.png');
            app.HelpButton.Text = 'Help';
            app.ContextMenu = uicontextmenu(app.figure1);
            app.OpenCHMhelpMenu = uimenu(app.ContextMenu);
            app.OpenCHMhelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.OpenCHMhelpMenu.Text = 'Open CHM help';
            app.OpenPDFhelpMenu = uimenu(app.ContextMenu);
            app.OpenPDFhelpMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenPDFhelpMenuSelected, true);
            app.OpenPDFhelpMenu.Text = 'Open PDF help';
            % Assign app.ContextMenu
            app.HelpButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = azfpdiag_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @azfpdiag_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end